classdef DeltaRobot_GUI_v2_CFPZ < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DeltaRobotGUI                 matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        PATHGENERATIONPanel           matlab.ui.container.Panel
        LinearPath_Acc_m_s2           matlab.ui.control.NumericEditField
        Linearaccelerationms2Label    matlab.ui.control.Label
        LinearPath_Vel_m_s            matlab.ui.control.NumericEditField
        LinearvelocitymsLabel         matlab.ui.control.Label
        LinearPath_Interval_mm        matlab.ui.control.NumericEditField
        DisplacementintervalmmLabel   matlab.ui.control.Label
        XYZLabel                      matlab.ui.control.Label
        LinearPath_X_Initial          matlab.ui.control.NumericEditField
        LinearPath_Z_End              matlab.ui.control.NumericEditField
        LinearPath_Z_Initial          matlab.ui.control.NumericEditField
        LinearPath_Y_End              matlab.ui.control.NumericEditField
        LinearPath_Y_Initial          matlab.ui.control.NumericEditField
        LinearPath_X_End              matlab.ui.control.NumericEditField
        EndmmLabel                    matlab.ui.control.Label
        InitialmmLabel                matlab.ui.control.Label
        LinearpathButton              matlab.ui.control.Button
        ACTIVEJOINTSPanel             matlab.ui.container.Panel
        Q2Angle                       matlab.ui.control.NumericEditField
        Q2Label                       matlab.ui.control.Label
        ForwardKinematicsButton       matlab.ui.control.Button
        Q1Angle                       matlab.ui.control.NumericEditField
        Q1Label                       matlab.ui.control.Label
        Q3Angle                       matlab.ui.control.NumericEditField
        Q3Label                       matlab.ui.control.Label
        WORKSPACEPanel                matlab.ui.container.Panel
        WorkspaceFwdKinematicsButton  matlab.ui.control.Button
        WorkspaceInvKinematicsButton  matlab.ui.control.Button
        ENDEFFECTORPOSITIONPanel      matlab.ui.container.Panel
        InverseKinematicsButton       matlab.ui.control.Button
        ZPosition                     matlab.ui.control.NumericEditField
        ZmmLabel                      matlab.ui.control.Label
        YPosition                     matlab.ui.control.NumericEditField
        YmmLabel                      matlab.ui.control.Label
        XPosition                     matlab.ui.control.NumericEditField
        XmmEditFieldLabel             matlab.ui.control.Label
        COMMUNICATIONSPanel           matlab.ui.container.Panel
        TCPConnectButton              matlab.ui.control.Button
        TCPDisconnectButton           matlab.ui.control.Button
        DYNAMICSPanel                 matlab.ui.container.Panel
        MaxAcelZ_m_s_2                matlab.ui.control.NumericEditField
        MaxacelZms2Label              matlab.ui.control.Label
        MaxAcelY_m_s_2                matlab.ui.control.NumericEditField
        MaxacelYms2Label              matlab.ui.control.Label
        MaxAcelX_m_s_2                matlab.ui.control.NumericEditField
        MaxacelXms2Label              matlab.ui.control.Label
        MaxVelZ_m_s                   matlab.ui.control.NumericEditField
        MaxvelZmsLabel                matlab.ui.control.Label
        MaxVelY_m_s                   matlab.ui.control.NumericEditField
        MaxvelYmsLabel                matlab.ui.control.Label
        MaxVelX_m_s                   matlab.ui.control.NumericEditField
        MaxvelXmsLabel                matlab.ui.control.Label
        MovementTime_s                matlab.ui.control.NumericEditField
        MovementtimesLabel            matlab.ui.control.Label
        MaxPower_W                    matlab.ui.control.NumericEditField
        MaxpowerWLabel                matlab.ui.control.Label
        MaxSpeed_RPM                  matlab.ui.control.NumericEditField
        MaxspeedRPMLabel              matlab.ui.control.Label
        MaxTorque_Nm                  matlab.ui.control.NumericEditField
        MaxtorqueNmLabel              matlab.ui.control.Label
        CalculateDynamicsButton       matlab.ui.control.Button
        RightPanel                    matlab.ui.container.Panel
        ShowspheresCheckBox           matlab.ui.control.CheckBox
        UITorque                      matlab.ui.control.UIAxes
        UIAxes                        matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        %Property % Description  
                
        % Robot constants
        % Robot Ubaldo
        %{
        rA = 80;    % Radio base fija [mm]
        rB = 40;    % Radio base movil [mm]
        L1 = 275;   % Longitud eslabon mas largo [mm]
        L2 = 100;   % Longitud eslabon mas corto [mm]
        ml1 = 0.047 % Masa eslabon L1 (mas largo) [Kg]
        ml2 = 0.115 % Masa eslabon L2 (mas corto) [Kg]
        mp = 0.06   % Masa efector final [Kg]
        %}
        
        % Robot UNAL
        %{
        rA = 96;    % Radio base fija [mm]
        rB = 40;    % Radio base movil [mm]
        L1 = 352;   % Longitud eslabon mas largo [mm]
        L2 = 172;   % Longitud eslabon mas corto [mm]
        ml1 = 0.15; % Masa eslabon L1 (mas largo) [Kg]  *** FALTA VERIFICAR MASAS ***
        ml2 = 0.12; % Masa eslabon L2 (mas corto) [Kg]  *** FALTA VERIFICAR MASAS ***
        mp = 0.15;   % Masa efector final [Kg]  *** FALTA VERIFICAR MASAS ***
        %}                  
        
        % Robot USTA                                
        %{
        rA = 100;   % Radio base fija [mm]
        rB = 50;    % Radio base movil [mm]
        L1 = 840;   % Longitud eslabon mas largo [mm]
        L2 = 310;   % Longitud eslabon mas corto [mm]        
        %}
        
        % Robot CFPZ construido 2021 comprobado OK        
        rA = 160.0;   % Radio base fija [mm]
        rB = 56.45;   % Radio base movil [mm]
        L1 = 310.6;   % Longitud brazo pasivo [mm]
        L2 = 147.5;   % Longitud brazo activo [mm]  
        
        % Posicion efector final cuando los brazos estan a 0°: X = 0, Y = 0, Z = -182.8832mm

        %MASAS VERIFICADAS ROBOT CONSTRUIDO EN 2021
        ml1 = 0.1706; % Masa eslabon L1 (mas largo) [Kg]  
        ml2 = 0.1211; % Masa eslabon L2 (mas corto) [Kg]  
        mp = 0.1745;   % Masa efector final [Kg]  
        
        % GUI sets
        Leg1;
        Leg2;
        Leg3;
        Mobile_Base;
        Rodilla1_Text;
        Rodilla2_Text;
        Rodilla3_Text;
        Tobillo1_Text; 
        Tobillo2_Text;
        Tobillo3_Text;
        Sphere1_Vector;
        Sphere2_Vector;
        Sphere3_Vector;
        Sphere1;
        Sphere2;
        Sphere3;
        
        % TCP Data
        TCP_Client;
        Arm1_Q1_Deg_Shadow = -9999.9;
        Arm2_Q1_Deg_Shadow = -9999.9;
        Arm3_Q1_Deg_Shadow = -9999.9;        
        
    end
    
    methods (Access = private)      
        function Plot_UpdateRobot(app, Caderas_PosXYZ_REF0, Rodillas_PosXYZ_REF0, Tobillos_PosXYZ_REF0, EF_PosXYZ, Esferas_PosXYZ_REF0, Leg1, Leg2, Leg3, Mobile_Base, Rodilla1_Text, Rodilla2_Text, Rodilla3_Text, Tobillo1_Text, Tobillo2_Text, Tobillo3_Text)
            % Leg 1         
            Leg1_X = [Caderas_PosXYZ_REF0(1, 1)   Rodillas_PosXYZ_REF0(1, 1)    Tobillos_PosXYZ_REF0(1, 1)];
            Leg1_Y = [Caderas_PosXYZ_REF0(2, 1)   Rodillas_PosXYZ_REF0(2, 1)    Tobillos_PosXYZ_REF0(2, 1)];
            Leg1_Z = [Caderas_PosXYZ_REF0(3, 1)   Rodillas_PosXYZ_REF0(3, 1)    Tobillos_PosXYZ_REF0(3, 1)];                                    
            set(app.Leg1, 'XData', Leg1_X, 'YData', Leg1_Y, 'ZData', Leg1_Z);     
            
            % Leg 2
            Leg2_X = [Caderas_PosXYZ_REF0(1, 2)   Rodillas_PosXYZ_REF0(1, 2)    Tobillos_PosXYZ_REF0(1, 2)];
            Leg2_Y = [Caderas_PosXYZ_REF0(2, 2)   Rodillas_PosXYZ_REF0(2, 2)    Tobillos_PosXYZ_REF0(2, 2)];
            Leg2_Z = [Caderas_PosXYZ_REF0(3, 2)   Rodillas_PosXYZ_REF0(3, 2)    Tobillos_PosXYZ_REF0(3, 2)];                   
            set(app.Leg2, 'XData', Leg2_X, 'YData', Leg2_Y, 'ZData', Leg2_Z);
            
            % Leg 3
            Leg3_X = [Caderas_PosXYZ_REF0(1, 3)   Rodillas_PosXYZ_REF0(1, 3)    Tobillos_PosXYZ_REF0(1, 3)];
            Leg3_Y = [Caderas_PosXYZ_REF0(2, 3)   Rodillas_PosXYZ_REF0(2, 3)    Tobillos_PosXYZ_REF0(2, 3)];
            Leg3_Z = [Caderas_PosXYZ_REF0(3, 3)   Rodillas_PosXYZ_REF0(3, 3)    Tobillos_PosXYZ_REF0(3, 3)];
            set(app.Leg3, 'XData', Leg3_X, 'YData', Leg3_Y, 'ZData', Leg3_Z);
            
            % Mobile base
            MobileBase_X = [Tobillos_PosXYZ_REF0(1, 1)    Tobillos_PosXYZ_REF0(1, 2)    Tobillos_PosXYZ_REF0(1, 3)    Tobillos_PosXYZ_REF0(1, 1)];
            MobileBase_Y = [Tobillos_PosXYZ_REF0(2, 1)    Tobillos_PosXYZ_REF0(2, 2)    Tobillos_PosXYZ_REF0(2, 3)    Tobillos_PosXYZ_REF0(2, 1)];
            MobileBase_Z = [Tobillos_PosXYZ_REF0(3, 1)    Tobillos_PosXYZ_REF0(3, 2)    Tobillos_PosXYZ_REF0(3, 3)    Tobillos_PosXYZ_REF0(3, 1)];
            set(app.Mobile_Base, 'XData', MobileBase_X, 'YData', MobileBase_Y, 'ZData', MobileBase_Z);
            
            % Text update
            set(app.Rodilla1_Text, 'Position', [Rodillas_PosXYZ_REF0(1, 1)  Rodillas_PosXYZ_REF0(2, 1)   Rodillas_PosXYZ_REF0(3, 1)]);
            set(app.Rodilla2_Text, 'Position', [Rodillas_PosXYZ_REF0(1, 2)  Rodillas_PosXYZ_REF0(2, 2)   Rodillas_PosXYZ_REF0(3, 2)]);
            set(app.Rodilla3_Text, 'Position', [Rodillas_PosXYZ_REF0(1, 3)  Rodillas_PosXYZ_REF0(2, 3)   Rodillas_PosXYZ_REF0(3, 3)]);
            
            set(app.Tobillo1_Text, 'Position', [Tobillos_PosXYZ_REF0(1, 1)  Tobillos_PosXYZ_REF0(2, 1)   Tobillos_PosXYZ_REF0(3, 1)]);
            set(app.Tobillo2_Text, 'Position', [Tobillos_PosXYZ_REF0(1, 2)  Tobillos_PosXYZ_REF0(2, 2)   Tobillos_PosXYZ_REF0(3, 2)]);
            set(app.Tobillo3_Text, 'Position', [Tobillos_PosXYZ_REF0(1, 3)  Tobillos_PosXYZ_REF0(2, 3)   Tobillos_PosXYZ_REF0(3, 3)]);
                        
            % Draw end effector 
            plot3(app.UIAxes, EF_PosXYZ(1), EF_PosXYZ(2), EF_PosXYZ(3), 'Color', 'r', 'Marker', "+", 'MarkerSize', 2);
                                
            % Draw spheres
            if (app.ShowspheresCheckBox.Value == 1)                
                % Delete GUI spheres instance
                DeleteSpheresGUI(app);
                
                % Create GUI spheres instance
                CreateSpheresGUI(app);
                
                % Draw sphere vector 1
                Sphere1_Vector_X = [Rodillas_PosXYZ_REF0(1, 1)   Esferas_PosXYZ_REF0(1, 1)];
                Sphere1_Vector_Y = [Rodillas_PosXYZ_REF0(2, 1)   Esferas_PosXYZ_REF0(2, 1)];
                Sphere1_Vector_Z = [Rodillas_PosXYZ_REF0(3, 1)   Esferas_PosXYZ_REF0(3, 1)];                
                set(app.Sphere1_Vector, 'XData', Sphere1_Vector_X, 'YData', Sphere1_Vector_Y, 'ZData', Sphere1_Vector_Z);
                            
                % Draw sphere vector 2
                Sphere2_Vector_X = [Rodillas_PosXYZ_REF0(1, 2)   Esferas_PosXYZ_REF0(1, 2)];
                Sphere2_Vector_Y = [Rodillas_PosXYZ_REF0(2, 2)   Esferas_PosXYZ_REF0(2, 2)];
                Sphere2_Vector_Z = [Rodillas_PosXYZ_REF0(3, 2)   Esferas_PosXYZ_REF0(3, 2)];
                set(app.Sphere2_Vector, 'XData', Sphere2_Vector_X, 'YData', Sphere2_Vector_Y, 'ZData', Sphere2_Vector_Z);
                
                % Draw sphere vector 3
                Sphere3_Vector_X = [Rodillas_PosXYZ_REF0(1, 3)   Esferas_PosXYZ_REF0(1, 3)];
                Sphere3_Vector_Y = [Rodillas_PosXYZ_REF0(2, 3)   Esferas_PosXYZ_REF0(2, 3)];
                Sphere3_Vector_Z = [Rodillas_PosXYZ_REF0(3, 3)   Esferas_PosXYZ_REF0(3, 3)];
                set(app.Sphere3_Vector, 'XData', Sphere3_Vector_X, 'YData', Sphere3_Vector_Y, 'ZData', Sphere3_Vector_Z);
                
                % Draw sphere 1
                [Sphere1_X, Sphere1_Y, Sphere1_Z] = sphere;            
                r = app.L1;
                Sphere1_X = (Sphere1_X * r) + Esferas_PosXYZ_REF0(1, 1);
                Sphere1_Y = (Sphere1_Y * r) + Esferas_PosXYZ_REF0(2, 1);
                Sphere1_Z = (Sphere1_Z * r) + Esferas_PosXYZ_REF0(3, 1);                
                set(app.Sphere1, 'XData', Sphere1_X, 'YData', Sphere1_Y, 'ZData', Sphere1_Z);
                
                % Draw sphere 2
                [Sphere2_X, Sphere2_Y, Sphere2_Z] = sphere;            
                %r = app.L1;
                Sphere2_X = (Sphere2_X * r) + Esferas_PosXYZ_REF0(1, 2);
                Sphere2_Y = (Sphere2_Y * r) + Esferas_PosXYZ_REF0(2, 2);
                Sphere2_Z = (Sphere2_Z * r) + Esferas_PosXYZ_REF0(3, 2);                
                set(app.Sphere2, 'XData', Sphere2_X, 'YData', Sphere2_Y, 'ZData', Sphere2_Z);
                
                % Draw sphere 3
                [Sphere3_X, Sphere3_Y, Sphere3_Z] = sphere;            
                %r = app.L1;
                Sphere3_X = (Sphere3_X * r) + Esferas_PosXYZ_REF0(1, 3);
                Sphere3_Y = (Sphere3_Y * r) + Esferas_PosXYZ_REF0(2, 3);
                Sphere3_Z = (Sphere3_Z * r) + Esferas_PosXYZ_REF0(3, 3);                
                set(app.Sphere3, 'XData', Sphere3_X, 'YData', Sphere3_Y, 'ZData', Sphere3_Z);            
            else
                % Delete GUI spheres instance
                DeleteSpheresGUI(app);
            end          
            
            pause(0.1);
        end           
               
        function CreateSpheresGUI(app)
            % Set sphere vector 1
            app.Sphere1_Vector = line(app.UIAxes, 'Color', 'm', 'LineWidth', 1, 'Marker', 'o', 'MarkerSize', 3);    
            
            % Set sphere vector 2
            app.Sphere2_Vector = line(app.UIAxes, 'Color', 'm', 'LineWidth', 1, 'Marker', 'o', 'MarkerSize', 3);    
            
            % Set sphere vector 3
            app.Sphere3_Vector = line(app.UIAxes, 'Color', 'm', 'LineWidth', 1, 'Marker', 'o', 'MarkerSize', 3);                
            
            % Set sphere 1           
            app.Sphere1 = surf(app.UIAxes);
            set(app.Sphere1, 'FaceAlpha', 0.15);
            shading(app.UIAxes, "interp");     
            
            % Set sphere 2      
            app.Sphere2 = surf(app.UIAxes);
            set(app.Sphere2, 'FaceAlpha', 0.15);
            shading(app.UIAxes, "interp");
            
            % Set sphere 3           
            app.Sphere3 = surf(app.UIAxes);
            set(app.Sphere3, 'FaceAlpha', 0.15);
            shading(app.UIAxes, "interp");            
        end
        
        function DeleteSpheresGUI(app)
            % Delete spheres vectors
            delete(app.Sphere1_Vector);
            delete(app.Sphere2_Vector);
            delete(app.Sphere3_Vector);
            
            % Delete spheres
            delete(app.Sphere1);
            delete(app.Sphere2);
            delete(app.Sphere3);
        end          
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc;
            cla(app.UIAxes, 'reset');
            grid(app.UIAxes, 'on');
            hold(app.UIAxes, 'on');              
            % title(app.UIAxes, 'Delta Robot GUI');
            
            % Change perspective
            view(app.UIAxes, [-37.5, 30]);
            
            % Plot dimensions
            % WorkSpaceRadio = ((app.L2 + app.L1) * 1.5) / 2; 
            WorkSpaceRadio = ((app.L2 + app.L1) * 1.5) / 1.75;
            axis(app.UIAxes, [-WorkSpaceRadio  WorkSpaceRadio -WorkSpaceRadio  WorkSpaceRadio -WorkSpaceRadio*1.5  WorkSpaceRadio/2]);
                        
            % Draw axes
            xlabel(app.UIAxes,'X [mm]');
            ylabel(app.UIAxes,'Y [mm]');
            zlabel(app.UIAxes,'Z [mm]');         
            
            % Draw origin vectors
            SysCoordinates1_X = [0 30]; 
            SysCoordinates1_Y = [0 0];
            SysCoordinates1_Z = [0 0];
            plot3(app.UIAxes,  SysCoordinates1_X, SysCoordinates1_Y, SysCoordinates1_Z, 'Color', 'r', 'LineWidth', 2);
            
            SysCoordinates2_X = [0 0];
            SysCoordinates2_Y = [0 30];
            SysCoordinates2_Z = [0 0];
            plot3(app.UIAxes,  SysCoordinates2_X, SysCoordinates2_Y, SysCoordinates2_Z, 'Color', 'g', 'LineWidth', 2);
            
            SysCoordinates3_X = [0 0];
            SysCoordinates3_Y = [0 0];
            SysCoordinates3_Z = [0 30];
            plot3(app.UIAxes,  SysCoordinates3_X, SysCoordinates3_Y, SysCoordinates3_Z, 'Color', 'b', 'LineWidth', 2);
            
            % Draw robot origin
            plot3(app.UIAxes, 0, 0, 0, 'Color', 'b', 'Marker', "+", 'MarkerSize', 7);    
            
            % Get XYZ desired positions from GUI
            %EF_PosXYZ = [app.XPosition.Value, app.YPosition.Value, app.ZPosition.Value];
            
            % Initialize robot position
            % EF_PosXYZ = [0, 0, -200];   %Efector final: Posicion [mm] -> OK
            % EF_PosXYZ = [0, 0, -750];   %Efector final: Posicion [mm] -> OK
            % EF_PosXYZ = [17.41055131, 0, -758.95];   %Efector final: Posicion [mm] -> OK       
            EF_PosXYZ = [0.000001, 0.000001, -174.9568];   % Efector final: Posicion [mm] -> OK robot CFPZ 2021
            %EF_PosXYZ = [0.000001, 0.000001, -758.9466];   %Efector final: Posicion [mm] -> OK USTA
            
            % Update X, Y, Z position
            app.XPosition.Value = EF_PosXYZ(1);
            app.YPosition.Value = EF_PosXYZ(2);
            app.ZPosition.Value = EF_PosXYZ(3);
            
            ShowMessages = 1;
            
            % CALCULAR CINEMATICA INVERSA
            [Result_InvKinematics, Angulos_Caderas_deg, Alphas_deg, Thetas_deg, Tobillos_PosXYZ_REF0] = Inverse_Kinematics(ShowMessages, EF_PosXYZ, app.rA, app.rB, app.L1, app.L2);
                        
            % CALCULAR CINEMATICA DIRECTA          
            [Result_FwdKinematics, Caderas_PosXYZ_REF0, Rodillas_PosXYZ_REF0, EF_PosXYZ_Calculada_REF0, Esferas_PosXYZ_REF0] = Forward_Kinematics(ShowMessages, Angulos_Caderas_deg, app.rA, app.rB, app.L1, app.L2);
                       
            % CALCULAR MATRIZ JACOBIANA
            [Result_JacobianMatrix, Jacobiano] = Jacobian_Matrix(ShowMessages, Angulos_Caderas_deg, Alphas_deg, Thetas_deg, app.L2);    
                    
            % Angulos_Caderas_deg
            
            % Update hips angles on GUI
            app.Q1Angle.Value = Angulos_Caderas_deg(1);
            app.Q2Angle.Value = Angulos_Caderas_deg(2);
            app.Q3Angle.Value = Angulos_Caderas_deg(3);
            
            % DEFINE GRAPHIC ITEMS
            
            % Set and draw fixed base 
            Fixed_Base = line(app.UIAxes, 'Color', 'b', 'LineWidth', 3); 
            FixedBase_X = [Caderas_PosXYZ_REF0(1, 1)   Caderas_PosXYZ_REF0(1, 2)   Caderas_PosXYZ_REF0(1, 3)   Caderas_PosXYZ_REF0(1, 1)];
            FixedBase_Y = [Caderas_PosXYZ_REF0(2, 1)   Caderas_PosXYZ_REF0(2, 2)   Caderas_PosXYZ_REF0(2, 3)   Caderas_PosXYZ_REF0(2, 1)];
            FixedBase_Z = [Caderas_PosXYZ_REF0(3, 1)   Caderas_PosXYZ_REF0(3, 2)   Caderas_PosXYZ_REF0(3, 3)   Caderas_PosXYZ_REF0(3, 1)];                                  
            set(Fixed_Base, 'XData', FixedBase_X, 'YData', FixedBase_Y, 'ZData', FixedBase_Z);
            
            % Set leg 1
            app.Leg1 = line(app.UIAxes, 'Color', 'r', 'LineWidth', 3, 'Marker', 'o', 'MarkerSize', 5);
            
            % Set leg 2
            app.Leg2 = line(app.UIAxes, 'Color', 'r', 'LineWidth', 3, 'Marker', 'o', 'MarkerSize', 5);    
            
            % Set leg 3
            app.Leg3 = line(app.UIAxes, 'Color', 'r', 'LineWidth', 3, 'Marker', 'o', 'MarkerSize', 5);
            
            % Set mobile base
            app.Mobile_Base = line(app.UIAxes, 'Color', '#7E5', 'LineWidth', 3);                  
            
            % TEXT INDICATORS
            % Hips (Fixed text)
            text(app.UIAxes, Caderas_PosXYZ_REF0(1, 1), Caderas_PosXYZ_REF0(2, 1), Caderas_PosXYZ_REF0(3, 1), '  C1', 'FontSize', 16, 'HorizontalAlignment', 'left');
            text(app.UIAxes, Caderas_PosXYZ_REF0(1, 2), Caderas_PosXYZ_REF0(2, 2), Caderas_PosXYZ_REF0(3, 2), '  C2', 'FontSize', 16, 'HorizontalAlignment', 'left');
            text(app.UIAxes, Caderas_PosXYZ_REF0(1, 3), Caderas_PosXYZ_REF0(2, 3), Caderas_PosXYZ_REF0(3, 3), '  C3', 'FontSize', 16, 'HorizontalAlignment', 'left');
            
            % Knees
            app.Rodilla1_Text = text(app.UIAxes, Rodillas_PosXYZ_REF0(1, 1), Rodillas_PosXYZ_REF0(2, 1), Rodillas_PosXYZ_REF0(3, 1), '  R1', 'FontSize', 16, 'HorizontalAlignment', 'left');
            app.Rodilla2_Text = text(app.UIAxes, Rodillas_PosXYZ_REF0(1, 2), Rodillas_PosXYZ_REF0(2, 2), Rodillas_PosXYZ_REF0(3, 2), '  R2', 'FontSize', 16, 'HorizontalAlignment', 'left');
            app.Rodilla3_Text = text(app.UIAxes, Rodillas_PosXYZ_REF0(1, 3), Rodillas_PosXYZ_REF0(2, 3), Rodillas_PosXYZ_REF0(3, 3), '  R3', 'FontSize', 16, 'HorizontalAlignment', 'left');
            
            % Ankles
            app.Tobillo1_Text = text(app.UIAxes, Tobillos_PosXYZ_REF0(1, 1), Tobillos_PosXYZ_REF0(2, 1), Tobillos_PosXYZ_REF0(3, 1), '  T1', 'FontSize', 16, 'HorizontalAlignment', 'left');
            app.Tobillo2_Text = text(app.UIAxes, Tobillos_PosXYZ_REF0(1, 2), Tobillos_PosXYZ_REF0(2, 2), Tobillos_PosXYZ_REF0(3, 2), '  T2', 'FontSize', 16, 'HorizontalAlignment', 'left');
            app.Tobillo3_Text = text(app.UIAxes, Tobillos_PosXYZ_REF0(1, 3), Tobillos_PosXYZ_REF0(2, 3), Tobillos_PosXYZ_REF0(3, 3), '  T3', 'FontSize', 16, 'HorizontalAlignment', 'left');
                      
            % Create GUI spheres instance
            CreateSpheresGUI(app)
            
            % Plot entire robot
            Plot_UpdateRobot(app, Caderas_PosXYZ_REF0, Rodillas_PosXYZ_REF0, Tobillos_PosXYZ_REF0, EF_PosXYZ_Calculada_REF0, Esferas_PosXYZ_REF0, app.Leg1, app.Leg2, app.Leg3, app.Mobile_Base, app.Rodilla1_Text, app.Rodilla2_Text, app.Rodilla3_Text, app.Tobillo1_Text, app.Tobillo2_Text, app.Tobillo3_Text);          
                    
        end

        % Button pushed function: ForwardKinematicsButton
        function ForwardKinematicsButtonPushed(app, event)
            disp('ForwardKinematicsButtonPushed') 
            
            % Get Theta1 angles from GUI
            Angulos_Caderas_deg(1) = app.Q1Angle.Value;
            Angulos_Caderas_deg(2) = app.Q2Angle.Value;
            Angulos_Caderas_deg(3) = app.Q3Angle.Value;
            
            ShowMessages = 1;
            
            % CALCULAR CINEMATICA DIRECTA            
            [Result_FwdKinematics, Caderas_PosXYZ_REF0, Rodillas_PosXYZ_REF0, EF_PosXYZ_Calculada_REF0, Esferas_PosXYZ_REF0] = Forward_Kinematics(ShowMessages, Angulos_Caderas_deg, app.rA, app.rB, app.L1, app.L2);
            
            % CALCULAR CINEMATICA INVERSA
            [Result_InvKinematics, Angulos_Caderas_deg, Alphas_deg, Thetas_deg, Tobillos_PosXYZ_REF0] = Inverse_Kinematics(ShowMessages, EF_PosXYZ_Calculada_REF0, app.rA, app.rB, app.L1, app.L2);
            
            % CALCULAR MATRIZ JACOBIANA
            [Result_JacobianMatrix, Jacobiano] = Jacobian_Matrix(ShowMessages, Angulos_Caderas_deg, Alphas_deg, Thetas_deg, app.L2);  
            
            if (Result_InvKinematics == 1 && Result_FwdKinematics == 1 && Result_JacobianMatrix == 1)
                % Update robot position on GUI
                app.XPosition.Value = EF_PosXYZ_Calculada_REF0(1);
                app.YPosition.Value = EF_PosXYZ_Calculada_REF0(2);
                app.ZPosition.Value = EF_PosXYZ_Calculada_REF0(3);
                
                % Plot robot
                Plot_UpdateRobot(app, Caderas_PosXYZ_REF0, Rodillas_PosXYZ_REF0, Tobillos_PosXYZ_REF0, EF_PosXYZ_Calculada_REF0, Esferas_PosXYZ_REF0, app.Leg1, app.Leg2, app.Leg3, app.Mobile_Base, app.Rodilla1_Text, app.Rodilla2_Text, app.Rodilla3_Text, app.Tobillo1_Text, app.Tobillo2_Text, app.Tobillo3_Text);
            end      
            
        end

        % Button pushed function: WorkspaceInvKinematicsButton
        function WorkspaceInvKinematicsButtonPushed(app, event)
            disp('WorkspaceInvKinematicsButtonPushed')   
            
            %EF_PosXYZ_REF0(1) = 0
            %EF_PosXYZ_REF0(2) = 0
            %EF_PosXYZ_REF0(3) = 0            
            
            ShowMessages = 0;
            
            % Position reached
            XMin_Reached = 1000;
            XMax_Reached = -1000;
            YMin_Reached = 1000;
            YMax_Reached = -1000;
            ZMin_Reached = 1000;
            ZMax_Reached = -1000;
            
            % Angles to get max positions
            XMin_Caderas_Angulos = [0 0 0]
            XMax_Caderas_Angulos = [0 0 0]
            YMin_Caderas_Angulos = [0 0 0]
            YMax_Caderas_Angulos = [0 0 0]
            ZMin_Caderas_Angulos = [0 0 0]
            ZMax_Caderas_Angulos = [0 0 0]     
            
            PosX_InitValue = -app.L1 - app.L2;
            PosX_EndValue = app.L1 + app.L2;
            PosX_Interval = 20;
            
            PosY_InitValue = -app.L1 - app.L2;
            PosY_EndValue = app.L1 + app.L2;
            PosY_Interval = 20;
            
            PosZ_InitValue = -app.L1 - app.L2;
            PosZ_EndValue = -app.L2;
            PosZ_Interval = 20;           
  
            for XPos = PosX_InitValue : PosX_Interval : PosX_EndValue                
                
                for YPos = PosY_InitValue : PosY_Interval : PosY_EndValue                    
                    
                    for ZPos = PosZ_InitValue: PosZ_Interval : PosZ_EndValue
                                                
                        EF_PosXYZ_REF0 = [XPos, YPos, ZPos] % Posicion efector final [mm]
                                    
                        % CALCULAR CINEMATICA INVERSA
                        [Result_InvKinematics, Angulos_CaderasCalculado_deg, Alphas_deg, Thetas_deg, Tobillos_PosXYZ_REF0] = Inverse_Kinematics(ShowMessages, EF_PosXYZ_REF0, app.rA, app.rB, app.L1, app.L2);
                                                
                        % CALCULAR CINEMATICA DIRECTA            
                        %[Result_FwdKinematics, Caderas_PosXYZ_REF0, Rodillas_PosXYZ_REF0, Tobillos_PosXYZ_REF0, EF_PosXYZ_Calculada_REF0, Esferas_PosXYZ_REF0, Alphas_deg, Thetas_deg] = Forward_Kinematics(Angulos_Caderas_deg, app.rA, app.rB, app.L1, app.L2);
                        [Result_FwdKinematics, Caderas_PosXYZ_REF0, Rodillas_PosXYZ_REF0, EF_PosXYZ_Calculada_REF0, Esferas_PosXYZ_REF0] = Forward_Kinematics(ShowMessages, Angulos_CaderasCalculado_deg, app.rA, app.rB, app.L1, app.L2);
                                                                                
                        % CALCULAR MATRIZ JACOBIANA
                        [Result_JacobianMatrix, Jacobiano] = Jacobian_Matrix(ShowMessages, Angulos_CaderasCalculado_deg, Alphas_deg, Thetas_deg, app.L2);   
                                                     
                        if (Result_InvKinematics == 1 && Result_FwdKinematics == 1 && Result_JacobianMatrix == 1)                            
                            % Update hips angles on GUI
                            app.Q1Angle.Value = Angulos_CaderasCalculado_deg(1);
                            app.Q2Angle.Value = Angulos_CaderasCalculado_deg(2);
                            app.Q3Angle.Value = Angulos_CaderasCalculado_deg(3);
                            
                            % Update robot position on GUI
                            app.XPosition.Value = EF_PosXYZ_REF0(1);
                            app.YPosition.Value = EF_PosXYZ_REF0(2);
                            app.ZPosition.Value = EF_PosXYZ_REF0(3);
                            
                            % Plot robot
                            Plot_UpdateRobot(app, Caderas_PosXYZ_REF0, Rodillas_PosXYZ_REF0, Tobillos_PosXYZ_REF0, EF_PosXYZ_REF0, Esferas_PosXYZ_REF0, app.Leg1, app.Leg2, app.Leg3, app.Mobile_Base, app.Rodilla1_Text, app.Rodilla2_Text, app.Rodilla3_Text, app.Tobillo1_Text, app.Tobillo2_Text, app.Tobillo3_Text);
                            
                            % EF_PosXYZ_Calculada_REF0
                            
                            % Check max positions reached
                            if (EF_PosXYZ_REF0(1) < XMin_Reached) % XMin
                               XMin_Reached = EF_PosXYZ_REF0(1)
                               XMin_Caderas_Angulos = [Angulos_CaderasCalculado_deg(1)   Angulos_CaderasCalculado_deg(2)   Angulos_CaderasCalculado_deg(3)]
                            end
                            
                            if (EF_PosXYZ_REF0(1) > XMax_Reached) % XMax
                               XMax_Reached = EF_PosXYZ_REF0(1)
                               XMax_Caderas_Angulos = [Angulos_CaderasCalculado_deg(1)   Angulos_CaderasCalculado_deg(2)   Angulos_CaderasCalculado_deg(3)]
                            end
                            
                            if (EF_PosXYZ_REF0(2) < YMin_Reached) % YMin
                               YMin_Reached = EF_PosXYZ_REF0(2)
                               YMin_Caderas_Angulos = [Angulos_CaderasCalculado_deg(1)   Angulos_CaderasCalculado_deg(2)   Angulos_CaderasCalculado_deg(3)]
                            end
                            
                            if (EF_PosXYZ_REF0(2) > YMax_Reached) % YMax
                               YMax_Reached = EF_PosXYZ_REF0(2)
                               YMax_Caderas_Angulos = [Angulos_CaderasCalculado_deg(1)   Angulos_CaderasCalculado_deg(2)   Angulos_CaderasCalculado_deg(3)]
                            end
                            
                            if (EF_PosXYZ_REF0(3) < ZMin_Reached) % ZMin
                               ZMin_Reached = EF_PosXYZ_REF0(3)
                               ZMin_Caderas_Angulos = [Angulos_CaderasCalculado_deg(1)   Angulos_CaderasCalculado_deg(2)   Angulos_CaderasCalculado_deg(3)]
                            end
                            
                            if (EF_PosXYZ_REF0(3) > ZMax_Reached) % ZMax
                               ZMax_Reached = EF_PosXYZ_REF0(3)
                               ZMax_Caderas_Angulos = [Angulos_CaderasCalculado_deg(1)   Angulos_CaderasCalculado_deg(2)   Angulos_CaderasCalculado_deg(3)]
                            end                           
                            
                        else                          
                            DisplayMessage = ['Singularidad Q1 = ', num2str(Angulos_CaderasCalculado_deg(1)), ' Q2 = ', num2str(Angulos_CaderasCalculado_deg(2)), ' Q3 = ', num2str(Angulos_CaderasCalculado_deg(3))];
                            disp(DisplayMessage);                  
                        end                          
                    end            
                end                  
            end      
            
            % Show final positions reached
            disp('Final position reached')
            
            DisplayMessage = ['Xmin = ', num2str(XMin_Reached), ' Q1 = ', num2str(XMin_Caderas_Angulos(1)), ' Q2 = ', num2str(XMin_Caderas_Angulos(2)), ' Q3 = ', num2str(XMin_Caderas_Angulos(3))];
            disp(DisplayMessage);   
            
            DisplayMessage = ['Xmax = ', num2str(XMax_Reached), ' Q1 = ', num2str(XMax_Caderas_Angulos(1)), ' Q2 = ', num2str(XMax_Caderas_Angulos(2)), ' Q3 = ', num2str(XMax_Caderas_Angulos(3))];
            disp(DisplayMessage);
            
            DisplayMessage = ['Ymin = ', num2str(YMin_Reached), ' Q1 = ', num2str(YMin_Caderas_Angulos(1)), ' Q2 = ', num2str(YMin_Caderas_Angulos(2)), ' Q3 = ', num2str(YMin_Caderas_Angulos(3))];
            disp(DisplayMessage);
            
            DisplayMessage = ['Ymax = ', num2str(YMax_Reached), ' Q1 = ', num2str(YMax_Caderas_Angulos(1)), ' Q2 = ', num2str(YMax_Caderas_Angulos(2)), ' Q3 = ', num2str(YMax_Caderas_Angulos(3))];
            disp(DisplayMessage);
            
            DisplayMessage = ['Zmin = ', num2str(ZMin_Reached), ' Q1 = ', num2str(ZMin_Caderas_Angulos(1)), ' Q2 = ', num2str(ZMin_Caderas_Angulos(2)), ' Q3 = ', num2str(ZMin_Caderas_Angulos(3))];
            disp(DisplayMessage);
            
            DisplayMessage = ['Zmax = ', num2str(ZMax_Reached), ' Q1 = ', num2str(ZMax_Caderas_Angulos(1)), ' Q2 = ', num2str(ZMax_Caderas_Angulos(2)), ' Q3 = ', num2str(ZMax_Caderas_Angulos(3))];
            disp(DisplayMessage);  
            
        end

        % Callback function
        function Funcion3dButtonPushed(app, event)
            
        end

        % Callback function
        function ExcelExportButtonPushed(app, event)
            disp('ExcelExportButtonPushed') 
            
            global datos            
            writematrix(datos', 'datos.xls');
            
            %cla(app.UIAxes, "reset");   % Resetear grafica
            %datos = [40, 15, 45];
            %pie3(app.UIAxes, datos, [1,1,0]);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.DeltaRobotGUI.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {1051, 1051};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {289, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Button pushed function: InverseKinematicsButton
        function InverseKinematicsButtonPushed(app, event)
            disp('InverseKinematicsButtonPushed') 
            
            % Set end effector position
            %EF_PosXYZ = [100, 0, -200];   %Efector final: Posicion [mm] -> SINGULARIDAD
            %EF_PosXYZ = [0, 100, -200];   %Efector final: Posicion [mm] -> OK
            %EF_PosXYZ = [-20, 15, -195];   %Efector final: Posicion [mm] -> OK
            %EF_PosXYZ = [18.1, -12, -99.37];   %Efector final: Posicion [mm] -> OK
            %EF_PosXYZ = [0, 0, -200];   %Efector final: Posicion [mm] -> SINGULARIDAD    
                       
            % Get XYZ desired positions from GUI
            EF_PosXYZ = [app.XPosition.Value, app.YPosition.Value, app.ZPosition.Value];
            
            ShowMessages = 1;
                       
            % CALCULAR CINEMATICA INVERSA
            [Result_InvKinematics, Angulos_Caderas_deg, Alphas_deg, Thetas_deg, Tobillos_PosXYZ_REF0] = Inverse_Kinematics(ShowMessages, EF_PosXYZ, app.rA, app.rB, app.L1, app.L2);
                        
            % CALCULAR CINEMATICA DIRECTA                
            [Result_FwdKinematics, Caderas_PosXYZ_REF0, Rodillas_PosXYZ_REF0, EF_PosXYZ_Calculada_REF0, Esferas_PosXYZ_REF0] = Forward_Kinematics(ShowMessages, Angulos_Caderas_deg, app.rA, app.rB, app.L1, app.L2);
            
            % VERIFICAR PORQUE LOS PARAMETROS QUE SE LE ENVIAN Y 
            % CALCULAR MATRIZ JACOBIANA
            [Result_JacobianMatrix, Jacobiano] = Jacobian_Matrix(ShowMessages, Angulos_Caderas_deg, Alphas_deg, Thetas_deg, app.L2);  
            
            if (Result_InvKinematics == 1 && Result_FwdKinematics == 1 && Result_JacobianMatrix == 1)
                % Update hips angles on GUI
                app.Q1Angle.Value = Angulos_Caderas_deg(1);
                app.Q2Angle.Value = Angulos_Caderas_deg(2);
                app.Q3Angle.Value = Angulos_Caderas_deg(3);
                
                % Plot robot
                Plot_UpdateRobot(app, Caderas_PosXYZ_REF0, Rodillas_PosXYZ_REF0, Tobillos_PosXYZ_REF0, EF_PosXYZ, Esferas_PosXYZ_REF0, app.Leg1, app.Leg2, app.Leg3, app.Mobile_Base, app.Rodilla1_Text, app.Rodilla2_Text, app.Rodilla3_Text, app.Tobillo1_Text, app.Tobillo2_Text, app.Tobillo3_Text);
            end                       
        end

        % Button pushed function: WorkspaceFwdKinematicsButton
        function WorkspaceFwdKinematicsButtonPushed(app, event)
            disp('WorkspaceFwdKinematicsButtonPushed') 
            
            %{ 
            Cadera1_InitValue_Deg = -90;
            Cadera1_EndValue_Deg = 90;
            Cadera1_Interval_Deg = 10;
            
            Cadera2_InitValue_Deg = -90;
            Cadera2_EndValue_Deg = 90;
            Cadera2_Interval_Deg = 10;
            
            Cadera3_InitValue_Deg = -90;
            Cadera3_EndValue_Deg = 90;
            Cadera3_Interval_Deg = 10;
            %}
            
            Cadera1_InitValue_Deg = -70;
            Cadera1_EndValue_Deg = 70;
            Cadera1_Interval_Deg = 10;
            
            Cadera2_InitValue_Deg = -70;
            Cadera2_EndValue_Deg = 70;
            Cadera2_Interval_Deg = 10;
            
            Cadera3_InitValue_Deg = -70;
            Cadera3_EndValue_Deg = 70;
            Cadera3_Interval_Deg = 10;
            
            ShowMessages = 0;
            
            % Position reached
            XMin_Reached = 1000;
            XMax_Reached = -1000;
            YMin_Reached = 1000;
            YMax_Reached = -1000;
            ZMin_Reached = 1000;
            ZMax_Reached = -1000;
            
            % Angles to get max positions
            XMin_Caderas_Angulos = [0 0 0]
            XMax_Caderas_Angulos = [0 0 0]
            YMin_Caderas_Angulos = [0 0 0]
            YMax_Caderas_Angulos = [0 0 0]
            ZMin_Caderas_Angulos = [0 0 0]
            ZMax_Caderas_Angulos = [0 0 0]            
  
            for Cadera1_Angulo_Deg = Cadera1_InitValue_Deg : Cadera1_Interval_Deg : Cadera1_EndValue_Deg                
                
                for Cadera2_Angulo_Deg = Cadera2_InitValue_Deg : Cadera2_Interval_Deg : Cadera2_EndValue_Deg                    
                    
                    for Cadera3_Angulo_Deg = Cadera3_InitValue_Deg: Cadera3_Interval_Deg : Cadera3_EndValue_Deg
                        
                        Angulos_Caderas_deg = [Cadera1_Angulo_Deg, Cadera2_Angulo_Deg, Cadera3_Angulo_Deg];  %Cadera: Angulos [°]
                                                
                        % CALCULAR CINEMATICA DIRECTA            
                        %[Result_FwdKinematics, Caderas_PosXYZ_REF0, Rodillas_PosXYZ_REF0, Tobillos_PosXYZ_REF0, EF_PosXYZ_Calculada_REF0, Esferas_PosXYZ_REF0, Alphas_deg, Thetas_deg] = Forward_Kinematics(Angulos_Caderas_deg, app.rA, app.rB, app.L1, app.L2);
                        [Result_FwdKinematics, Caderas_PosXYZ_REF0, Rodillas_PosXYZ_REF0, EF_PosXYZ_Calculada_REF0, Esferas_PosXYZ_REF0] = Forward_Kinematics(ShowMessages, Angulos_Caderas_deg, app.rA, app.rB, app.L1, app.L2);
                                  
                         % CALCULAR CINEMATICA INVERSA
                        [Result_InvKinematics, Angulos_CaderasCalculado_deg, Alphas_deg, Thetas_deg, Tobillos_PosXYZ_REF0] = Inverse_Kinematics(ShowMessages, EF_PosXYZ_Calculada_REF0, app.rA, app.rB, app.L1, app.L2);
                        
                        % CALCULAR MATRIZ JACOBIANA
                        [Result_JacobianMatrix, Jacobiano] = Jacobian_Matrix(ShowMessages, Angulos_Caderas_deg, Alphas_deg, Thetas_deg, app.L2);   
                                                     
                        if (Result_InvKinematics == 1 && Result_FwdKinematics == 1 && Result_JacobianMatrix == 1)
                            % Update hips angles on GUI
                            app.Q1Angle.Value = Angulos_Caderas_deg(1);
                            app.Q2Angle.Value = Angulos_Caderas_deg(2);
                            app.Q3Angle.Value = Angulos_Caderas_deg(3);
                            
                            % Update robot position on GUI
                            app.XPosition.Value = EF_PosXYZ_Calculada_REF0(1);
                            app.YPosition.Value = EF_PosXYZ_Calculada_REF0(2);
                            app.ZPosition.Value = EF_PosXYZ_Calculada_REF0(3);
                            
                            % Plot robot
                            Plot_UpdateRobot(app, Caderas_PosXYZ_REF0, Rodillas_PosXYZ_REF0, Tobillos_PosXYZ_REF0, EF_PosXYZ_Calculada_REF0, Esferas_PosXYZ_REF0, app.Leg1, app.Leg2, app.Leg3, app.Mobile_Base, app.Rodilla1_Text, app.Rodilla2_Text, app.Rodilla3_Text, app.Tobillo1_Text, app.Tobillo2_Text, app.Tobillo3_Text);
                            
                            % EF_PosXYZ_Calculada_REF0
                            
                            % Check max positions reached
                            if (EF_PosXYZ_Calculada_REF0(1) < XMin_Reached) % XMin
                               XMin_Reached = EF_PosXYZ_Calculada_REF0(1)
                               XMin_Caderas_Angulos = [Cadera1_Angulo_Deg   Cadera2_Angulo_Deg   Cadera3_Angulo_Deg]
                            end
                            
                            if (EF_PosXYZ_Calculada_REF0(1) > XMax_Reached) % XMax
                               XMax_Reached = EF_PosXYZ_Calculada_REF0(1)
                               XMax_Caderas_Angulos = [Cadera1_Angulo_Deg   Cadera2_Angulo_Deg   Cadera3_Angulo_Deg]
                            end
                            
                            if (EF_PosXYZ_Calculada_REF0(2) < YMin_Reached) % YMin
                               YMin_Reached = EF_PosXYZ_Calculada_REF0(2)
                               YMin_Caderas_Angulos = [Cadera1_Angulo_Deg   Cadera2_Angulo_Deg   Cadera3_Angulo_Deg]
                            end
                            
                            if (EF_PosXYZ_Calculada_REF0(2) > YMax_Reached) % YMax
                               YMax_Reached = EF_PosXYZ_Calculada_REF0(2)
                               YMax_Caderas_Angulos = [Cadera1_Angulo_Deg   Cadera2_Angulo_Deg   Cadera3_Angulo_Deg]
                            end
                            
                            if (EF_PosXYZ_Calculada_REF0(3) < ZMin_Reached) % ZMin
                               ZMin_Reached = EF_PosXYZ_Calculada_REF0(3)
                               ZMin_Caderas_Angulos = [Cadera1_Angulo_Deg   Cadera2_Angulo_Deg   Cadera3_Angulo_Deg]
                            end
                            
                            if (EF_PosXYZ_Calculada_REF0(3) > ZMax_Reached) % ZMax
                               ZMax_Reached = EF_PosXYZ_Calculada_REF0(3)
                               ZMax_Caderas_Angulos = [Cadera1_Angulo_Deg   Cadera2_Angulo_Deg   Cadera3_Angulo_Deg]
                            end                           
                            
                        else                          
                            DisplayMessage = ['Singularidad Q1 = ', num2str(Cadera1_Angulo_Deg), ' Q2 = ', num2str(Cadera2_Angulo_Deg), ' Q3 = ', num2str(Cadera3_Angulo_Deg)];
                            disp(DisplayMessage);                  
                        end                          
                    end            
                end                  
            end      
            
            % Show final positions reached
            disp('Final position reached')
            
            DisplayMessage = ['Xmin = ', num2str(XMin_Reached), ' Q1 = ', num2str(XMin_Caderas_Angulos(1)), ' Q2 = ', num2str(XMin_Caderas_Angulos(2)), ' Q3 = ', num2str(XMin_Caderas_Angulos(3))];
            disp(DisplayMessage);   
            
            DisplayMessage = ['Xmax = ', num2str(XMax_Reached), ' Q1 = ', num2str(XMax_Caderas_Angulos(1)), ' Q2 = ', num2str(XMax_Caderas_Angulos(2)), ' Q3 = ', num2str(XMax_Caderas_Angulos(3))];
            disp(DisplayMessage);
            
            DisplayMessage = ['Ymin = ', num2str(YMin_Reached), ' Q1 = ', num2str(YMin_Caderas_Angulos(1)), ' Q2 = ', num2str(YMin_Caderas_Angulos(2)), ' Q3 = ', num2str(YMin_Caderas_Angulos(3))];
            disp(DisplayMessage);
            
            DisplayMessage = ['Ymax = ', num2str(YMax_Reached), ' Q1 = ', num2str(YMax_Caderas_Angulos(1)), ' Q2 = ', num2str(YMax_Caderas_Angulos(2)), ' Q3 = ', num2str(YMax_Caderas_Angulos(3))];
            disp(DisplayMessage);
            
            DisplayMessage = ['Zmin = ', num2str(ZMin_Reached), ' Q1 = ', num2str(ZMin_Caderas_Angulos(1)), ' Q2 = ', num2str(ZMin_Caderas_Angulos(2)), ' Q3 = ', num2str(ZMin_Caderas_Angulos(3))];
            disp(DisplayMessage);
            
            DisplayMessage = ['Zmax = ', num2str(ZMax_Reached), ' Q1 = ', num2str(ZMax_Caderas_Angulos(1)), ' Q2 = ', num2str(ZMax_Caderas_Angulos(2)), ' Q3 = ', num2str(ZMax_Caderas_Angulos(3))];
            disp(DisplayMessage);            
            
        end

        % Button pushed function: TCPConnectButton
        function TCPConnectButtonPushed(app, event)
            disp('TCPConnectButtonPushed')             
                       
            % Connect to server
            app.TCP_Client = tcpclient("127.0.0.1", 6000, "Timeout", 10, "ConnectTimeout", 30); 
            
            % Create continuos reading function (24 bytes = 3 double data)
            configureCallback(app.TCP_Client, "byte", 24, @TCP_ReadDataFunction) 
            
            % TCP read data event (Triggered when Matlab has received 24 bytes = 3 double data)
            function TCP_ReadDataFunction(src, evt)                
                disp('TCPReadData') 
                disp(evt)               
                
                % Get data received
                %TCP_ReadData = read(src, src.BytesAvailableFcnCount)
                TCP_ReadData = read(src, 3, "double")          
                
                bl_Update_Robot = 0;
                
                TCP_ReadData(1)
                app.Arm1_Q1_Deg_Shadow
                
                if TCP_ReadData(1) ~= app.Arm1_Q1_Deg_Shadow
                    app.Arm1_Q1_Deg_Shadow = TCP_ReadData(1)
                    bl_Update_Robot = 1
                    
                    disp('Arm1_Q1_Deg position changed')
                end
                
                if TCP_ReadData(2) ~= app.Arm2_Q1_Deg_Shadow
                    app.Arm2_Q1_Deg_Shadow = TCP_ReadData(2)
                    bl_Update_Robot = 1
                    
                    disp('Arm2_Q1_Deg position changed')
                end
                
                if TCP_ReadData(3) ~= app.Arm3_Q1_Deg_Shadow
                    app.Arm3_Q1_Deg_Shadow = TCP_ReadData(3)
                    bl_Update_Robot = 1
                    
                    disp('Arm3_Q1_Deg position changed')
                end
                
                if bl_Update_Robot == 1
                    disp('Update robot graphic')
                    
                    % Update q positions
                    app.Q1Angle.Value = TCP_ReadData(1);
                    app.Q2Angle.Value = TCP_ReadData(2);
                    app.Q3Angle.Value = TCP_ReadData(3);
                    
                    % Get Theta1 angles from GUI
                    Angulos_Caderas_deg(1) = app.Q1Angle.Value;
                    Angulos_Caderas_deg(2) = app.Q2Angle.Value;
                    Angulos_Caderas_deg(3) = app.Q3Angle.Value;
                    
                    ShowMessages = 0;
                    
                    % CALCULAR CINEMATICA DIRECTA            
                    [Result_FwdKinematics, Caderas_PosXYZ_REF0, Rodillas_PosXYZ_REF0, EF_PosXYZ_Calculada_REF0, Esferas_PosXYZ_REF0] = Forward_Kinematics(ShowMessages, Angulos_Caderas_deg, app.rA, app.rB, app.L1, app.L2);
                    
                    % CALCULAR CINEMATICA INVERSA
                    [Result_InvKinematics, Angulos_Caderas_deg, Alphas_deg, Thetas_deg, Tobillos_PosXYZ_REF0] = Inverse_Kinematics(ShowMessages, EF_PosXYZ_Calculada_REF0, app.rA, app.rB, app.L1, app.L2);
                    
                    % CALCULAR MATRIZ JACOBIANA
                    [Result_JacobianMatrix, Jacobiano] = Jacobian_Matrix(ShowMessages, Angulos_Caderas_deg, Alphas_deg, Thetas_deg, app.L2);  
                    
                    if (Result_InvKinematics == 1 && Result_FwdKinematics == 1 && Result_JacobianMatrix == 1)
                        % Update robot position on GUI
                        app.XPosition.Value = EF_PosXYZ_Calculada_REF0(1);
                        app.YPosition.Value = EF_PosXYZ_Calculada_REF0(2);
                        app.ZPosition.Value = EF_PosXYZ_Calculada_REF0(3);
                        
                        % Plot robot
                        Plot_UpdateRobot(app, Caderas_PosXYZ_REF0, Rodillas_PosXYZ_REF0, Tobillos_PosXYZ_REF0, EF_PosXYZ_Calculada_REF0, Esferas_PosXYZ_REF0, app.Leg1, app.Leg2, app.Leg3, app.Mobile_Base, app.Rodilla1_Text, app.Rodilla2_Text, app.Rodilla3_Text, app.Tobillo1_Text, app.Tobillo2_Text, app.Tobillo3_Text);
                    end      
                end        
                
            end
        end

        % Callback function
        function TCPReaddataButtonPushed(app, event)
            disp('TCPReaddataButtonPushed') 
            
            %{
            TCP_ReadData = read(app.TCP_Client, 3, "double")          
            
            disp('TCP data received') 
            
            % Update q positions
            app.Q1Angle.Value = TCP_ReadData(1);
            app.Q2Angle.Value = TCP_ReadData(2);
            app.Q3Angle.Value = TCP_ReadData(3);
            
            % Get Theta1 angles from GUI
            Angulos_Caderas_deg(1) = app.Q1Angle.Value;
            Angulos_Caderas_deg(2) = app.Q2Angle.Value;
            Angulos_Caderas_deg(3) = app.Q3Angle.Value;
            
            ShowMessages = 1;
            
            % CALCULAR CINEMATICA DIRECTA            
            [Result_FwdKinematics, Caderas_PosXYZ_REF0, Rodillas_PosXYZ_REF0, EF_PosXYZ_Calculada_REF0, Esferas_PosXYZ_REF0] = Forward_Kinematics(ShowMessages, Angulos_Caderas_deg, app.rA, app.rB, app.L1, app.L2);
            
            % CALCULAR CINEMATICA INVERSA
            [Result_InvKinematics, Angulos_Caderas_deg, Alphas_deg, Thetas_deg, Tobillos_PosXYZ_REF0] = Inverse_Kinematics(ShowMessages, EF_PosXYZ_Calculada_REF0, app.rA, app.rB, app.L1, app.L2);
            
            % CALCULAR MATRIZ JACOBIANA
            [Result_JacobianMatrix, Jacobiano] = Jacobian_Matrix(ShowMessages, Angulos_Caderas_deg, Alphas_deg, Thetas_deg, app.L2);  
            
            if (Result_InvKinematics == 1 && Result_FwdKinematics == 1 && Result_JacobianMatrix == 1)
                % Update robot position on GUI
                app.XPosition.Value = EF_PosXYZ_Calculada_REF0(1);
                app.YPosition.Value = EF_PosXYZ_Calculada_REF0(2);
                app.ZPosition.Value = EF_PosXYZ_Calculada_REF0(3);
                
                % Plot robot
                Plot_UpdateRobot(app, Caderas_PosXYZ_REF0, Rodillas_PosXYZ_REF0, Tobillos_PosXYZ_REF0, EF_PosXYZ_Calculada_REF0, Esferas_PosXYZ_REF0, app.Leg1, app.Leg2, app.Leg3, app.Mobile_Base, app.Rodilla1_Text, app.Rodilla2_Text, app.Rodilla3_Text, app.Tobillo1_Text, app.Tobillo2_Text, app.Tobillo3_Text);
            end    
            %}
        end

        % Button pushed function: TCPDisconnectButton
        function TCPDisconnectButtonPushed(app, event)
            clear app.TCP_Client
            % echotcpip("off")
        end

        % Button pushed function: LinearpathButton
        function LinearpathButtonPushed(app, event)
            disp('LinearpathButtonPushed') 
            
            PositionX_Initial = app.LinearPath_X_Initial.Value;
            PositionY_Initial = app.LinearPath_Y_Initial.Value;
            PositionZ_Initial = app.LinearPath_Z_Initial.Value;
            PositionX_Target = app.LinearPath_X_End.Value;
            PositionY_Target = app.LinearPath_Y_End.Value;
            PositionZ_Target = app.LinearPath_Z_End.Value;
            
            %{
            DiscretedPositions = 15
            
            X_LinearPositions = linspace(PositionX_Initial, PositionX_Target, DiscretedPositions)            
            Y_LinearPositions = linspace(PositionY_Initial, PositionY_Target, DiscretedPositions)
            Z_LinearPositions = linspace(PositionZ_Initial, PositionZ_Target, DiscretedPositions)
            
            % a = (PositionX_Target - PositionX_Initial) / (DiscretedPositions - 1)
            % b = (PositionY_Target - PositionY_Initial) / (DiscretedPositions - 1)
            % c = (PositionZ_Target - PositionZ_Initial) / (DiscretedPositions - 1)
            
            % Draw end effector desired positions            
            for i= 1: DiscretedPositions
                plot3(app.UIAxes, X_LinearPositions(i), Y_LinearPositions(i), Z_LinearPositions(i), 'Color', 'b', 'Marker', "+", 'MarkerSize', 10);
            end            
            %}

            % Draw desired path        
            LinearPath_XPositions = [PositionX_Initial    PositionX_Target]; 
            LinearPath_YPositions = [PositionY_Initial    PositionY_Target]; 
            LinearPath_ZPositions = [PositionZ_Initial    PositionZ_Target]; 
            
            plot3(app.UIAxes,  LinearPath_XPositions, LinearPath_YPositions, LinearPath_ZPositions, 'Color', 'r', 'LineWidth', 2);
                                                    
            % GENERATE PATH
            
            % Calcular magnitud del vector
            Vec_Mag = sqrt((PositionX_Target - PositionX_Initial)^2 + (PositionY_Target - PositionY_Initial)^2 + (PositionZ_Target - PositionZ_Initial)^2)
            
            % Calcular angulos del vector (Cosenos directores -> Angulos a desplazar desde el vector unitario de cada eje)
            alpha_deg = acosd((PositionX_Target - PositionX_Initial) / Vec_Mag)
            beta_deg = acosd((PositionY_Target - PositionY_Initial) / Vec_Mag)
            gamma_deg = acosd((PositionZ_Target - PositionZ_Initial) / Vec_Mag)
            
            %{
            
            DeltaDesplazamiento = app.LinearPath_Interval_mm.Value;
            
            % Inicializar las posiciones del vector de desplazamiento            
            VecDesplazamiento_Mag = 0;
            i = 0;
            ShowMessages = 0;
            
            while VecDesplazamiento_Mag < Vec_Mag
                i = i + 1;
                 
                % Calcular posiciones del vector de desplazamiento desde la posicion inicial
                VecDesplazamiento_X = PositionX_Initial + i*DeltaDesplazamiento*cosd(alpha_deg);
                VecDesplazamiento_Y = PositionY_Initial + i*DeltaDesplazamiento*cosd(beta_deg);
                VecDesplazamiento_Z = PositionZ_Initial + i*DeltaDesplazamiento*cosd(gamma_deg);      
                
                % Calcular magnitud del vector de desplazamiento
                VecDesplazamiento_Mag = sqrt((VecDesplazamiento_X - PositionX_Initial)^2 + (VecDesplazamiento_Y - PositionY_Initial)^2 + (VecDesplazamiento_Z - PositionZ_Initial)^2);
                
                if (VecDesplazamiento_Mag < Vec_Mag)
                     %{
                    % Generar vector de desplazamiento partiendo desde la posicion inicial 
                    V_Unit_XPositions = [PositionX_Initial    VecDesplazamiento_X] 
                    V_Unit_YPositions = [PositionY_Initial    VecDesplazamiento_Y] 
                    V_Unit_ZPositions = [PositionZ_Initial    VecDesplazamiento_Z]    
                    
                    % Graficar vector de desplazamiento
                    plot3(app.UIAxes,  V_Unit_XPositions, V_Unit_YPositions, V_Unit_ZPositions, 'Color', 'g', 'LineWidth', 5);    
                    %}
                    
                    % Graficar posicion deseada del efector final
                    plot3(app.UIAxes, VecDesplazamiento_X, VecDesplazamiento_Y, VecDesplazamiento_Z, 'Color', 'g', 'Marker', "+", 'MarkerSize', 10);                
                    
                    LinearPathPositions(i, 1) = VecDesplazamiento_X;
                    LinearPathPositions(i, 2) = VecDesplazamiento_Y;
                    LinearPathPositions(i, 3) = VecDesplazamiento_Z; 
                else
                    % Graficar posicion final deseada
                    plot3(app.UIAxes, PositionX_Target, PositionY_Target, PositionZ_Target, 'Color', 'g', 'Marker', "+", 'MarkerSize', 10);                
                    
                    LinearPathPositions(i, 1) = PositionX_Target;
                    LinearPathPositions(i, 2) = PositionY_Target;
                    LinearPathPositions(i, 3) = PositionZ_Target; 
                end      
                
                % PLOT ROBOT
                
                % Update end effector position
                app.XPosition.Value = LinearPathPositions(i, 1);
                app.YPosition.Value = LinearPathPositions(i, 2);
                app.ZPosition.Value = LinearPathPositions(i, 3);
                
                % Get XYZ desired positions from GUI
                EF_PosXYZ = [app.XPosition.Value, app.YPosition.Value, app.ZPosition.Value];
                                       
                % CALCULAR CINEMATICA INVERSA
                [Result_InvKinematics, Angulos_Caderas_deg, Alphas_deg, Thetas_deg, Tobillos_PosXYZ_REF0] = Inverse_Kinematics(ShowMessages, LinearPathPositions(i, :), app.rA, app.rB, app.L1, app.L2);
                            
                % CALCULAR CINEMATICA DIRECTA                
                [Result_FwdKinematics, Caderas_PosXYZ_REF0, Rodillas_PosXYZ_REF0, LinearPathPositions(i, :), Esferas_PosXYZ_REF0] = Forward_Kinematics(ShowMessages, Angulos_Caderas_deg, app.rA, app.rB, app.L1, app.L2);
                
                % CALCULAR MATRIZ JACOBIANA
                [Result_JacobianMatrix, Jacobiano] = Jacobian_Matrix(ShowMessages, Angulos_Caderas_deg, Alphas_deg, Thetas_deg, app.L2);  
                
                if (Result_InvKinematics == 1 && Result_FwdKinematics == 1 && Result_JacobianMatrix == 1)
                    % Update hips angles on GUI
                    app.Q1Angle.Value = Angulos_Caderas_deg(1);
                    app.Q2Angle.Value = Angulos_Caderas_deg(2);
                    app.Q3Angle.Value = Angulos_Caderas_deg(3);
                    
                    % Plot robot
                    Plot_UpdateRobot(app, Caderas_PosXYZ_REF0, Rodillas_PosXYZ_REF0, Tobillos_PosXYZ_REF0, EF_PosXYZ, Esferas_PosXYZ_REF0, app.Leg1, app.Leg2, app.Leg3, app.Mobile_Base, app.Rodilla1_Text, app.Rodilla2_Text, app.Rodilla3_Text, app.Tobillo1_Text, app.Tobillo2_Text, app.Tobillo3_Text);
                end                
                
                pause(0.1);
                
            end    
            
            % LinearPathPositions % Mostrar posiciones 
                        
            writematrix(LinearPathPositions, 'LinearPath.xlsx');    % Crear archivo de excel con posiciones deseadas
            %}

            % ****************  DATOS PARA EL CONTROLADOR DE MOVIMIENTO ***************
            LinearPath_Time_Resol_s = 0.001;    % Resolucion de la generacion de la trayectoria
            
            LinearPath_Vel_m_s = app.LinearPath_Vel_m_s.Value;
            LinearPath_Acc_m_s2 = app.LinearPath_Acc_m_s2.Value;
            
            % Convertir a mm/s y mm/s^2
            LinearPath_Vel_mm_s = LinearPath_Vel_m_s * 1000;
            LinearPath_Acc_mm_s2 = LinearPath_Acc_m_s2 * 1000;
            
            % Calcular tiempo de aceleracion
            LinearPath_AccTime_s = (LinearPath_Vel_mm_s - 0) / LinearPath_Acc_mm_s2
            
            % Calcular velocidad maxima de cada eje independientemente
            LinearPath_VelX_Max_mm_s = LinearPath_Vel_mm_s * cosd(alpha_deg);
            LinearPath_VelY_Max_mm_s = LinearPath_Vel_mm_s * cosd(beta_deg);
            LinearPath_VelZ_Max_mm_s = LinearPath_Vel_mm_s * cosd(gamma_deg);
            
            % Calcular aceleracion de cada eje independiente
            LinearPath_AccX_mm_s2 = (LinearPath_VelX_Max_mm_s - 0) / LinearPath_AccTime_s;
            LinearPath_AccY_mm_s2 = (LinearPath_VelY_Max_mm_s - 0) / LinearPath_AccTime_s;
            LinearPath_AccZ_mm_s2 = (LinearPath_VelZ_Max_mm_s - 0) / LinearPath_AccTime_s;
            
            % Velocidad inicial ejes
            LinearPath_VelX_Initial_mm_s = 0;
            LinearPath_VelY_Initial_mm_s = 0;
            LinearPath_VelZ_Initial_mm_s = 0;
            
            % Calular posicion final de X al finalizar aceleracion
            LinearPath_XPosition_EndAccel_mm = PositionX_Initial + (LinearPath_VelX_Initial_mm_s * LinearPath_AccTime_s) + (0.5 * LinearPath_AccX_mm_s2 * LinearPath_AccTime_s^2)
                        
            % Calcular posicion de X al empezar a desacelerar
            LinearPath_XPosition_StartDeaccel_mm = PositionX_Target - LinearPath_XPosition_EndAccel_mm
            
            % Verificar si en el recorrido se alcanza a llegar a la velocidad maxima
            LinearPath_CanReachMaxVel = 0;
            
            %PositionX_Target / 2
            
            if (LinearPath_XPosition_EndAccel_mm <= PositionX_Target / 2)
                LinearPath_CanReachMaxVel = 1 
            else
                LinearPath_CanReachMaxVel = 0
            end
            
            if (PositionX_Initial == PositionX_Target) && (PositionY_Initial == PositionY_Target) && (PositionZ_Initial == PositionZ_Target)
                disp('No se requiere mover el robot') 
            else
                % Inicializar variables
                t = 1;
                Ctrl_ActualTime_s = 0;
                
                %Ctrl_XPrevious_Acc_mm_s2 = 0;
                Ctrl_XPrevious_Vel_mm_s = 0;
                Ctrl_XPrevious_Pos_mm = PositionX_Initial;                
                Ctrl_XActual_Vel_mm_s(1, 1) = 0;
                Ctrl_XActual_Pos_mm(1, 1) = PositionX_Initial;
                
                %Ctrl_YPrevious_Acc_mm_s2 = 0;
                Ctrl_YPrevious_Vel_mm_s = 0;
                Ctrl_YPrevious_Pos_mm = PositionY_Initial;
                Ctrl_YActual_Vel_mm_s(1, 1) = 0;
                Ctrl_YActual_Pos_mm(1, 1) = PositionY_Initial;
                
                %Ctrl_ZPrevious_Acc_mm_s2 = 0;
                Ctrl_ZPrevious_Vel_mm_s = 0;
                Ctrl_ZPrevious_Pos_mm = PositionZ_Initial;
                Ctrl_ZActual_Vel_mm_s(1, 1) = 0;
                Ctrl_ZActual_Pos_mm(1, 1) = PositionZ_Initial; 
                
                %
                Ctrl_Distancia_Acel_mm = 0;
                
                Ctrl_Etapa = 1;  % Aceleracion
                disp('Acelerando') 
                               
                while abs(Ctrl_XActual_Pos_mm(t, 1)) < abs(LinearPath_XPosition_EndAccel_mm) && abs(Ctrl_XActual_Pos_mm(t, 1)) < abs(PositionX_Target / 2)  % Realizar aceleracion
                    % Tiempo
                    t = t + 1;
                    Ctrl_ActualTime_s = (t - 1) / 1000;
                    
                    % Grabar velocidades anteriores
                    Ctrl_XPrevious_Vel_mm_s = Ctrl_XActual_Vel_mm_s(t-1, 1);
                    Ctrl_YPrevious_Vel_mm_s = Ctrl_YActual_Vel_mm_s(t-1, 1);
                    Ctrl_ZPrevious_Vel_mm_s = Ctrl_ZActual_Vel_mm_s(t-1, 1);
                    
                    % Grabar posiciones anteriores
                    Ctrl_XPrevious_Pos_mm = Ctrl_XActual_Pos_mm(t-1, 1);
                    Ctrl_YPrevious_Pos_mm = Ctrl_YActual_Pos_mm(t-1, 1);
                    Ctrl_ZPrevious_Pos_mm = Ctrl_ZActual_Pos_mm(t-1, 1);
                    
                    Ctrl_XActual_Acc_mm_s2(t, 1) = LinearPath_AccX_mm_s2;
                    Ctrl_XActual_Vel_mm_s(t, 1) = Ctrl_XPrevious_Vel_mm_s + (Ctrl_XActual_Acc_mm_s2(t, 1) * LinearPath_Time_Resol_s);
                    Ctrl_XActual_Pos_mm(t, 1) = Ctrl_XPrevious_Pos_mm + (Ctrl_XPrevious_Vel_mm_s * LinearPath_Time_Resol_s) + (0.5 * Ctrl_XActual_Acc_mm_s2(t, 1) * LinearPath_Time_Resol_s^2);               
                    
                    Ctrl_YActual_Acc_mm_s2(t, 1) = LinearPath_AccY_mm_s2;
                    Ctrl_YActual_Vel_mm_s(t, 1) = Ctrl_YPrevious_Vel_mm_s + (Ctrl_YActual_Acc_mm_s2(t, 1) * LinearPath_Time_Resol_s);
                    Ctrl_YActual_Pos_mm(t, 1) = Ctrl_YPrevious_Pos_mm + (Ctrl_YPrevious_Vel_mm_s * LinearPath_Time_Resol_s) + (0.5 * Ctrl_YActual_Acc_mm_s2(t, 1) * LinearPath_Time_Resol_s^2);               
                                        
                    Ctrl_ZActual_Acc_mm_s2(t, 1) = LinearPath_AccZ_mm_s2;
                    Ctrl_ZActual_Vel_mm_s(t, 1) = Ctrl_ZPrevious_Vel_mm_s + (Ctrl_ZActual_Acc_mm_s2(t, 1) * LinearPath_Time_Resol_s);
                    Ctrl_ZActual_Pos_mm(t, 1) = Ctrl_ZPrevious_Pos_mm + (Ctrl_ZPrevious_Vel_mm_s * LinearPath_Time_Resol_s) + (0.5 * Ctrl_ZActual_Acc_mm_s2(t, 1) * LinearPath_Time_Resol_s^2);               
                    
                    
                    if LinearPath_CanReachMaxVel == 0 && abs(Ctrl_XActual_Pos_mm(t, 1)) >= abs(PositionX_Target / 2)
                        disp('Correccion 0') 
                                                
                        Ctrl_XActual_Acc_mm_s2(t, 1) = LinearPath_AccX_mm_s2 * -1.0;
                        Ctrl_XActual_Vel_mm_s(t, 1) = Ctrl_XPrevious_Vel_mm_s + (Ctrl_XActual_Acc_mm_s2(t, 1) * LinearPath_Time_Resol_s);
                        Ctrl_XActual_Pos_mm(t, 1) = Ctrl_XPrevious_Pos_mm + (Ctrl_XPrevious_Vel_mm_s * LinearPath_Time_Resol_s) + (0.5 * Ctrl_XActual_Acc_mm_s2(t, 1) * LinearPath_Time_Resol_s^2);               
                             
                        Ctrl_YActual_Acc_mm_s2(t, 1) = LinearPath_AccY_mm_s2 * -1.0;
                        Ctrl_YActual_Vel_mm_s(t, 1) = Ctrl_YPrevious_Vel_mm_s + (Ctrl_YActual_Acc_mm_s2(t, 1) * LinearPath_Time_Resol_s);
                        Ctrl_YActual_Pos_mm(t, 1) = Ctrl_YPrevious_Pos_mm + (Ctrl_YPrevious_Vel_mm_s * LinearPath_Time_Resol_s) + (0.5 * Ctrl_YActual_Acc_mm_s2(t, 1) * LinearPath_Time_Resol_s^2);               
                             
                        Ctrl_ZActual_Acc_mm_s2(t, 1) = LinearPath_AccZ_mm_s2 * -1.0;
                        Ctrl_ZActual_Vel_mm_s(t, 1) = Ctrl_ZPrevious_Vel_mm_s + (Ctrl_ZActual_Acc_mm_s2(t, 1) * LinearPath_Time_Resol_s);
                        Ctrl_ZActual_Pos_mm(t, 1) = Ctrl_ZPrevious_Pos_mm + (Ctrl_ZPrevious_Vel_mm_s * LinearPath_Time_Resol_s) + (0.5 * Ctrl_ZActual_Acc_mm_s2(t, 1) * LinearPath_Time_Resol_s^2);               
                             
                    end
                    
                    if (abs(Ctrl_XActual_Pos_mm(t, 1)) > abs(LinearPath_XPosition_EndAccel_mm))
                        disp('Correccion 1') 
                        
                        Ctrl_XActual_Vel_mm_s(t, 1) = LinearPath_VelX_Max_mm_s;
                        Ctrl_XActual_Acc_mm_s2(t,1) = (Ctrl_XActual_Vel_mm_s(t, 1) - Ctrl_XActual_Vel_mm_s(t - 1, 1)) / LinearPath_Time_Resol_s;
                        Ctrl_XActual_Pos_mm(t, 1) = Ctrl_XPrevious_Pos_mm + (Ctrl_XPrevious_Vel_mm_s * LinearPath_Time_Resol_s) + (0.5 * Ctrl_XActual_Acc_mm_s2(t, 1) * LinearPath_Time_Resol_s^2);       
                        
                        Ctrl_YActual_Vel_mm_s(t, 1) = LinearPath_VelY_Max_mm_s;
                        Ctrl_YActual_Acc_mm_s2(t,1) = (Ctrl_YActual_Vel_mm_s(t, 1) - Ctrl_YActual_Vel_mm_s(t - 1, 1)) / LinearPath_Time_Resol_s;
                        Ctrl_YActual_Pos_mm(t, 1) = Ctrl_YPrevious_Pos_mm + (Ctrl_YPrevious_Vel_mm_s * LinearPath_Time_Resol_s) + (0.5 * Ctrl_YActual_Acc_mm_s2(t, 1) * LinearPath_Time_Resol_s^2);                       
                        
                        Ctrl_ZActual_Vel_mm_s(t, 1) = LinearPath_VelZ_Max_mm_s;
                        Ctrl_ZActual_Acc_mm_s2(t,1) = (Ctrl_ZActual_Vel_mm_s(t, 1) - Ctrl_ZActual_Vel_mm_s(t - 1, 1)) / LinearPath_Time_Resol_s;
                        Ctrl_ZActual_Pos_mm(t, 1) = Ctrl_ZPrevious_Pos_mm + (Ctrl_ZPrevious_Vel_mm_s * LinearPath_Time_Resol_s) + (0.5 * Ctrl_ZActual_Acc_mm_s2(t, 1) * LinearPath_Time_Resol_s^2);                       
                    end
                end
                
                disp('t actual: ') 
                t
                
                if (LinearPath_CanReachMaxVel == 1) 
                    Ctrl_Etapa = 2;  % Velocidad crucero
                    disp('Velocidad crucero') 
                    
                    while abs(Ctrl_XActual_Pos_mm(t, 1)) < abs(LinearPath_XPosition_StartDeaccel_mm)  % Calcular posiciones con velocidad crucero
                        % Tiempo
                        t = t + 1;
                        Ctrl_ActualTime_s = (t - 1) / 1000;
                        
                        % Grabar velocidades anterior
                        Ctrl_XPrevious_Vel_mm_s = Ctrl_XActual_Vel_mm_s(t-1, 1);
                        Ctrl_YPrevious_Vel_mm_s = Ctrl_YActual_Vel_mm_s(t-1, 1);
                        Ctrl_ZPrevious_Vel_mm_s = Ctrl_ZActual_Vel_mm_s(t-1, 1);
                        
                        % Grabar posiciones anteriores
                        Ctrl_XPrevious_Pos_mm = Ctrl_XActual_Pos_mm(t-1, 1);
                        Ctrl_YPrevious_Pos_mm = Ctrl_YActual_Pos_mm(t-1, 1);
                        Ctrl_ZPrevious_Pos_mm = Ctrl_ZActual_Pos_mm(t-1, 1);
                                                
                        Ctrl_XActual_Acc_mm_s2(t, 1) = 0;
                        Ctrl_XActual_Vel_mm_s(t, 1) = LinearPath_VelX_Max_mm_s;
                        Ctrl_XActual_Pos_mm(t, 1) = Ctrl_XPrevious_Pos_mm + (Ctrl_XPrevious_Vel_mm_s * LinearPath_Time_Resol_s) + (0.5 * Ctrl_XActual_Acc_mm_s2(t, 1) * LinearPath_Time_Resol_s^2);               
                        
                        Ctrl_YActual_Acc_mm_s2(t, 1) = 0;
                        Ctrl_YActual_Vel_mm_s(t, 1) = LinearPath_VelY_Max_mm_s;
                        Ctrl_YActual_Pos_mm(t, 1) = Ctrl_YPrevious_Pos_mm + (Ctrl_YPrevious_Vel_mm_s * LinearPath_Time_Resol_s) + (0.5 * Ctrl_YActual_Acc_mm_s2(t, 1) * LinearPath_Time_Resol_s^2);               
                        
                        Ctrl_ZActual_Acc_mm_s2(t, 1) = 0;
                        Ctrl_ZActual_Vel_mm_s(t, 1) = LinearPath_VelZ_Max_mm_s;
                        Ctrl_ZActual_Pos_mm(t, 1) = Ctrl_ZPrevious_Pos_mm + (Ctrl_ZPrevious_Vel_mm_s * LinearPath_Time_Resol_s) + (0.5 * Ctrl_ZActual_Acc_mm_s2(t, 1) * LinearPath_Time_Resol_s^2);               
                                                                       
                        if (abs(Ctrl_XActual_Pos_mm(t, 1)) > abs(LinearPath_XPosition_StartDeaccel_mm))
                            disp('Correccion 2') 
                            
                            Ctrl_XActual_Acc_mm_s2(t, 1) = LinearPath_AccX_mm_s2 * -1;
                            Ctrl_XActual_Vel_mm_s(t, 1) = Ctrl_XPrevious_Vel_mm_s + (Ctrl_XActual_Acc_mm_s2(t, 1) * LinearPath_Time_Resol_s);
                            Ctrl_XActual_Pos_mm(t, 1) = Ctrl_XPrevious_Pos_mm + (Ctrl_XPrevious_Vel_mm_s * LinearPath_Time_Resol_s) + (0.5 * Ctrl_XActual_Acc_mm_s2(t, 1) * LinearPath_Time_Resol_s^2);      
                            
                            Ctrl_YActual_Acc_mm_s2(t, 1) = LinearPath_AccY_mm_s2 * -1;
                            Ctrl_YActual_Vel_mm_s(t, 1) = Ctrl_YPrevious_Vel_mm_s + (Ctrl_YActual_Acc_mm_s2(t, 1) * LinearPath_Time_Resol_s);
                            Ctrl_YActual_Pos_mm(t, 1) = Ctrl_YPrevious_Pos_mm + (Ctrl_YPrevious_Vel_mm_s * LinearPath_Time_Resol_s) + (0.5 * Ctrl_YActual_Acc_mm_s2(t, 1) * LinearPath_Time_Resol_s^2);                     
                            
                            Ctrl_ZActual_Acc_mm_s2(t, 1) = LinearPath_AccZ_mm_s2 * -1;
                            Ctrl_ZActual_Vel_mm_s(t, 1) = Ctrl_ZPrevious_Vel_mm_s + (Ctrl_ZActual_Acc_mm_s2(t, 1) * LinearPath_Time_Resol_s);
                            Ctrl_ZActual_Pos_mm(t, 1) = Ctrl_ZPrevious_Pos_mm + (Ctrl_ZPrevious_Vel_mm_s * LinearPath_Time_Resol_s) + (0.5 * Ctrl_ZActual_Acc_mm_s2(t, 1) * LinearPath_Time_Resol_s^2);                     
                        end
                    end
                    
                    disp('t actual: ') 
                    t
                end            
                
                Ctrl_Etapa = 3;  % Desaceleracion
                disp('Desacelerando') 
                
                while (abs(Ctrl_XActual_Pos_mm(t, 1)) < abs(PositionX_Target))  % Realizar desaceleracion
                    % Tiempo
                    t = t + 1;
                    Ctrl_ActualTime_s = (t - 1) / 1000;
                                        
                    % Grabar velocidades anterior
                    Ctrl_XPrevious_Vel_mm_s = Ctrl_XActual_Vel_mm_s(t-1, 1);
                    Ctrl_YPrevious_Vel_mm_s = Ctrl_YActual_Vel_mm_s(t-1, 1);
                    Ctrl_ZPrevious_Vel_mm_s = Ctrl_ZActual_Vel_mm_s(t-1, 1);
                    
                    % Grabar posiciones anteriores
                    Ctrl_XPrevious_Pos_mm = Ctrl_XActual_Pos_mm(t-1, 1);
                    Ctrl_YPrevious_Pos_mm = Ctrl_YActual_Pos_mm(t-1, 1);
                    Ctrl_ZPrevious_Pos_mm = Ctrl_ZActual_Pos_mm(t-1, 1);
                    
                    Ctrl_XActual_Acc_mm_s2(t, 1) = LinearPath_AccX_mm_s2 * -1.0;
                    Ctrl_XActual_Vel_mm_s(t, 1) = Ctrl_XPrevious_Vel_mm_s + (Ctrl_XActual_Acc_mm_s2(t, 1) * LinearPath_Time_Resol_s);
                    Ctrl_XActual_Pos_mm(t, 1) = Ctrl_XPrevious_Pos_mm + (Ctrl_XPrevious_Vel_mm_s * LinearPath_Time_Resol_s) + (0.5 * Ctrl_XActual_Acc_mm_s2(t, 1) * LinearPath_Time_Resol_s^2);               
                                 
                    Ctrl_YActual_Acc_mm_s2(t, 1) = LinearPath_AccY_mm_s2 * -1.0;
                    Ctrl_YActual_Vel_mm_s(t, 1) = Ctrl_YPrevious_Vel_mm_s + (Ctrl_YActual_Acc_mm_s2(t, 1) * LinearPath_Time_Resol_s);
                    Ctrl_YActual_Pos_mm(t, 1) = Ctrl_YPrevious_Pos_mm + (Ctrl_YPrevious_Vel_mm_s * LinearPath_Time_Resol_s) + (0.5 * Ctrl_YActual_Acc_mm_s2(t, 1) * LinearPath_Time_Resol_s^2);               
                                                     
                    Ctrl_ZActual_Acc_mm_s2(t, 1) = LinearPath_AccZ_mm_s2 * -1.0;
                    Ctrl_ZActual_Vel_mm_s(t, 1) = Ctrl_ZPrevious_Vel_mm_s + (Ctrl_ZActual_Acc_mm_s2(t, 1) * LinearPath_Time_Resol_s);
                    Ctrl_ZActual_Pos_mm(t, 1) = Ctrl_ZPrevious_Pos_mm + (Ctrl_ZPrevious_Vel_mm_s * LinearPath_Time_Resol_s) + (0.5 * Ctrl_ZActual_Acc_mm_s2(t, 1) * LinearPath_Time_Resol_s^2);               
                                                     
                    if (Ctrl_XActual_Vel_mm_s(t, 1) <= 0)
                        disp('Correccion 3') 
                        
                        disp('t actual: ') 
                        t             
                        
                        Ctrl_XActual_Pos_mm(t, 1) = PositionX_Target;
                        Ctrl_XActual_Vel_mm_s(t, 1) = 0;
                        Ctrl_XActual_Acc_mm_s2(t, 1) = (Ctrl_XActual_Vel_mm_s(t, 1) - Ctrl_XPrevious_Vel_mm_s) / LinearPath_Time_Resol_s;   
                        
                        Ctrl_YActual_Pos_mm(t, 1) = PositionY_Target;
                        Ctrl_YActual_Vel_mm_s(t, 1) = 0;
                        Ctrl_YActual_Acc_mm_s2(t, 1) = (Ctrl_YActual_Vel_mm_s(t, 1) - Ctrl_YPrevious_Vel_mm_s) / LinearPath_Time_Resol_s;     
                        
                        Ctrl_ZActual_Pos_mm(t, 1) = PositionZ_Target;
                        Ctrl_ZActual_Vel_mm_s(t, 1) = 0;
                        Ctrl_ZActual_Acc_mm_s2(t, 1) = (Ctrl_ZActual_Vel_mm_s(t, 1) - Ctrl_ZPrevious_Vel_mm_s) / LinearPath_Time_Resol_s;     
                    end
                    
                    %{
                    if (abs(Ctrl_XActual_Pos_mm(t, 1)) > abs(PositionX_Target))
                        disp('Correccion 3') 
                        
                        disp('t actual: ') 
                        t                
                        
                        Ctrl_XActual_Pos_mm(t, 1)
                        Ctrl_XActual_Vel_m_s(t, 1)
                        Ctrl_XActual_Acc_m_s2(t, 1)           
                        
                        Ctrl_XActual_Pos_mm(t, 1) = PositionX_Target;
                        
                        Ctrl_XActual_Pos_mm(t, 1)
                        
                        PositionX_Target
                    end
                    %}
                end
                                
                CiclosTotales = t
                % CiclosDesacelerando = t - InicioCicloDesaceleracion
                
                %Ctrl_XActual_Pos_mm
                %Ctrl_XActual_Vel_mm_s
                %Ctrl_XActual_Acc_mm_s2
                                
                LinearPath(:,1) = Ctrl_XActual_Pos_mm;
                LinearPath(:,2) = Ctrl_XActual_Vel_mm_s;
                LinearPath(:,3) = Ctrl_XActual_Acc_mm_s2;                
                
                LinearPath(:,4) = Ctrl_YActual_Pos_mm;
                LinearPath(:,5) = Ctrl_YActual_Vel_mm_s;
                LinearPath(:,6) = Ctrl_YActual_Acc_mm_s2;
                                
                LinearPath(:,7) = Ctrl_ZActual_Pos_mm;
                LinearPath(:,8) = Ctrl_ZActual_Vel_mm_s;
                LinearPath(:,9) = Ctrl_ZActual_Acc_mm_s2;                
                
                % Crear archivo de excel con todos datos 
                writematrix(LinearPath, 'LinearPath_PVA.xlsx');
                
                LinearPath_Pos(:,1) = Ctrl_XActual_Pos_mm;
                LinearPath_Pos(:,2) = Ctrl_YActual_Pos_mm;
                LinearPath_Pos(:,3) = Ctrl_ZActual_Pos_mm;
                
                % Crear archivo de excel con los datos de posicion
                writematrix(LinearPath_Pos, 'LinearPath_P.xlsx');
                
            end            

        end

        % Button pushed function: CalculateDynamicsButton
        function CalculateDynamicsButtonPushed(app, event)
            disp('CalculateDynamicsButtonPushed') 
            clc;
            
            % POSICIONES EFECTOR FINAL
            % Capturar posiciones deseadas de la trayectoria
            EF_Positions_XYZ_mm = readmatrix('1_EF_XYZ_Positions.xlsx')            
            
            % Capturar posiciones X, Y, Z de manera independiente en vectores fila
            Cx_mm = EF_Positions_XYZ_mm(:, 1);
            Cy_mm = EF_Positions_XYZ_mm(:, 2);
            Cz_mm = EF_Positions_XYZ_mm(:, 3);
            
            % Convertir a vector columna
            Cx_mm = Cx_mm';
            Cy_mm = Cy_mm';
            Cz_mm = Cz_mm';
                        
            %Capturar la cant de posiciones deseadas
            Aux = size(EF_Positions_XYZ_mm);
            CantPosiciones = Aux(1);  
            
            % Tiempo total del movimiento                        
            TiempoTotal_s = app.MovementTime_s.Value;
            
            DeltaTiempo_s = TiempoTotal_s / (CantPosiciones - 1);  % Cada mov tiene un tiempo igual
            
            for i = 1 : CantPosiciones
                TiempoActual_s(i) = DeltaTiempo_s * (i-1);
            end
                        
            ShowMessages = 0;
            
            for i = 1 : CantPosiciones
                EF_PosXYZ_REF0_mm(i, 1) = Cx_mm(i);
                EF_PosXYZ_REF0_mm(i, 2) = Cy_mm(i);
                EF_PosXYZ_REF0_mm(i, 3) = Cz_mm(i);
                
                %EF_PosXYZ_REF0
                
                % CALCULAR CINEMATICA INVERSA
                [Result_InvKinematics, Angulos_Caderas_deg, Alphas_deg, Thetas_deg, Tobillos_PosXYZ_REF0] = Inverse_Kinematics(ShowMessages, EF_PosXYZ_REF0_mm(i, :), app.rA, app.rB, app.L1, app.L2);
            
                % Add data of active angles                 
                MotorsPositions_deg(i, 1) = Angulos_Caderas_deg(1);
                MotorsPositions_deg(i, 2) = Angulos_Caderas_deg(2);
                MotorsPositions_deg(i, 3) = Angulos_Caderas_deg(3);
                
                % Add data of alpha angles                
                AlphasPositions_deg(i, 1) = Alphas_deg(1);
                AlphasPositions_deg(i, 2) = Alphas_deg(2);
                AlphasPositions_deg(i, 3) = Alphas_deg(3);
                
                % Add data of theta angles
                ThetasPositions_deg(i, 1) = Thetas_deg(1);
                ThetasPositions_deg(i, 2) = Thetas_deg(2);
                ThetasPositions_deg(i, 3) = Thetas_deg(3);            
            end
            
            MotorsPositions_deg;     %Mostrar matriz de los angulos de las caderas
            AlphasPositions_deg;     %Mostrar matriz de los angulos alpha
            ThetasPositions_deg;     %Mostrar matriz de los angulos theta
            
            % Crear archivo de excel con las angulos de las caderas 
            writematrix(MotorsPositions_deg, '2_ActiveAngles_Deg.xlsx');
            
            % Crear archivo de excel con los angulos alpha
            writematrix(AlphasPositions_deg, '3_AlphaAngles_Deg.xlsx');
            
            % Crear archivo de excel con las posiciones de las caderas
            writematrix(ThetasPositions_deg, '4_ThetaAngles_Deg.xlsx');
                          
            % POSICIONES ARTICULACIONES ACTIVAS
                        
            % Capturar posiciones motores de manera independiente en
            % vectores fila
            motorang1_deg = MotorsPositions_deg(:, 1);
            motorang2_deg = MotorsPositions_deg(:, 2);
            motorang3_deg = MotorsPositions_deg(:, 3);
            
            % Convertir a vector columna
            motorang1_deg = motorang1_deg';
            motorang2_deg = motorang2_deg';
            motorang3_deg = motorang3_deg';
            
            % POSICIONES ARTICULACIONES PASIVAS (ALPHA)
                        
            % Capturar posiciones alpha de manera independiente en
            % vectores fila
            alpha1_deg = AlphasPositions_deg(:, 1);
            alpha2_deg = AlphasPositions_deg(:, 2);
            alpha3_deg = AlphasPositions_deg(:, 3);
            
            % Convertir a vector columna
            alpha1_deg = alpha1_deg';
            alpha2_deg = alpha2_deg';
            alpha3_deg = alpha3_deg';    
            
            % POSICIONES ARTICULACIONES PASIVAS (THETA)
                        
            % Capturar posiciones theta de manera independiente en
            % vectores fila
            theta1_deg = ThetasPositions_deg(:, 1);
            theta2_deg = ThetasPositions_deg(:, 2);
            theta3_deg = ThetasPositions_deg(:, 3);
            
            % Convertir a vector columna
            theta1_deg = theta1_deg';
            theta2_deg = theta2_deg';
            theta3_deg = theta3_deg';       
            
            % Desfase entre brazos
            Psi1_rad = deg2rad(0);
            Psi2_rad = deg2rad(120);
            Psi3_rad = deg2rad(240);
            
            % Gravity m/s2
            g_ms2 = 9.80665;
            
            % Convertir unidades de mm a m
            Cx_m = Cx_mm/1000;
            Cy_m = Cy_mm/1000;
            Cz_m = Cz_mm/1000;
            rA_m = app.rA/1000;
            rB_m = app.rB/1000;
            L2_m = app.L2/1000;
            
            % Convertir unidades de grados a rad
            q1_rad = deg2rad(motorang1_deg);  
            q2_rad = deg2rad(motorang2_deg);
            q3_rad = deg2rad(motorang3_deg);
            
            % Calculo de velocidades y aceleraciones
            
            for i = 1 : CantPosiciones - 1
                        
                % Velocidad angular del motor 1 [rad/s]
                aux1_rad_s = (q1_rad(i + 1) - q1_rad(i)) / (TiempoActual_s(i + 1) - TiempoActual_s(i));   
                Aux1_rad_s(i) = aux1_rad_s; % Vector fila concatenando todos los wM1
                VelAngM1_rad_s = [0 Aux1_rad_s];
                
                % Velocidad angular del motor 2 [rad/s]
                aux2_rad_s = (q2_rad(i + 1) -q2_rad(i)) / (TiempoActual_s(i + 1) - TiempoActual_s(i));
                Aux2_rad_s(i) = aux2_rad_s;
                VelAngM2_rad_s = [0 Aux2_rad_s];
                
                % Velocidad angular del motor 3 [rad/s]
                aux3_rad_s = (q3_rad(i + 1) - q3_rad(i)) / (TiempoActual_s(i + 1) - TiempoActual_s(i));
                Aux3_rad_s(i) = aux3_rad_s;
                VelAngM3_rad_s = [0 Aux3_rad_s];
                         
                
                % Aceleracion angular del motor 1 [rad/s2]
                aux1_rad_s2 = (VelAngM1_rad_s(i+1) - VelAngM1_rad_s(i)) / (TiempoActual_s(i+1) - TiempoActual_s(i)); % -0.0931   0.1602  0.0014
                Aux1_rad_s2(i) = aux1_rad_s2;
                AcelAng_M1_rad_s2 = [0 Aux1_rad_s2];
                
                % Aceleracion angular del motor 2 [rad/s2]
                aux2_rad_s2 = (VelAngM2_rad_s(i+1) - VelAngM2_rad_s(i)) / (TiempoActual_s(i+1) - TiempoActual_s(i));
                Aux2_rad_s2(i) = aux2_rad_s2;
                AcelAng_M2_rad_s2 = [0 Aux2_rad_s2];
                
                % Aceleracion angular del motor 3 [rad/s2]
                aux3_rad_s2 = (VelAngM3_rad_s(i+1) - VelAngM3_rad_s(i)) / (TiempoActual_s(i+1) - TiempoActual_s(i));
                Aux3_rad_s2(i) = aux3_rad_s2;
                AcelAng_M3_rad_s2 = [0 Aux3_rad_s2];                     
            end
            
            % Velocidades de los motores
            VelAngM1_rad_s %Vector fila de las velocidades del motor 1 desde velocidad = 0 
            VelAngM2_rad_s
            VelAngM3_rad_s
            
            % Aceleraciones de los motores
            AcelAng_M1_rad_s2
            AcelAng_M2_rad_s2
            AcelAng_M3_rad_s2
                  
            % Jacobian Matrix             
            for i = 1 : CantPosiciones - 1
        
                % Velocity of the end efector mm/s
        
                % j1x=cosd(q1(i)+alpha1(i))*cosd(theta1(i))*cosd(Psi1)+sind(Psi1)*sind(theta1(i));
                % j1y=cosd(Psi1)*sind(theta1(i))-cosd(q1(i)+alpha1(i))*cosd(theta1(i))*sind(Psi1);
                % j1z=sind(q1(i)+theta1(i))*cosd(theta1(i));
                % jsol1=L2*cosd(theta1(i))*sind(alpha1(i))*WangM1(i);
                % 
                % j2x=cosd(q2(i)+alpha2(i))*cosd(theta2(i))*cosd(Psi2)+sind(Psi2)*sind(theta2(i));
                % j2y=cosd(Psi2)*sind(theta2(i))-cosd(q2(i)+alpha2(i))*cosd(theta2(i))*sind(Psi2);
                % j2z=sind(q2(i)+theta2(i))*cosd(theta2(i));
                % jsol2=L2*cosd(theta2(i))*sind(alpha2(i))*WangM2(i);
                % 
                % j3x=cosd(q3(i)+alpha3(i))*cosd(theta3(i))*cosd(Psi3)+sind(Psi3)*sind(theta3(i));
                % j3y=cosd(Psi3)*sind(theta3(i))-cosd(q3(i)+alpha3(i))*cosd(theta3(i))*sind(Psi3);
                % j3z=sind(q3(i)+theta3(i))*cosd(theta3(i));
                % jsol3=L2*cosd(theta3(i))*sind(alpha3(i))*WangM3(i);
                % 
                % A=[j1x j1y j1z;j2x j2y j2z;j3x j3y j3z];
                % B=[jsol1;jsol2;jsol3];
                % InvA=inv(A);
                % SolVc=InvA*B;
                % 
                % VCX=SolVc(1,1);
                % VCY=SolVc(2,1);
                % VCZ=SolVc(3,1);
        
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                % Solucion numerica 
                % 
                % Velocidad del efector final sobre cada uno de los ejes [m/s]
                
                Aux1_m_s = (Cx_m(i+1) - Cx_m(i)) / (TiempoActual_s(i+1) - TiempoActual_s(i));
                vx_m_s(i) = Aux1_m_s;
                VelX_EndEffector_m_s = [0 vx_m_s];
        
                Aux2_m_s = (Cy_m(i+1) - Cy_m(i)) / (TiempoActual_s(i+1) - TiempoActual_s(i));
                vy_m_s(i) = Aux2_m_s;
                VelY_EndEffector_m_s = [0 vy_m_s];
        
                Aux3_m_s = (Cz_m(i+1) - Cz_m(i)) / (TiempoActual_s(i+1) - TiempoActual_s(i));
                vz_m_s(i) = Aux3_m_s;
                VelZ_EndEffector_m_s = [0 vz_m_s];
            end           
            
            VelX_EndEffector_m_s
            VelY_EndEffector_m_s
            VelZ_EndEffector_m_s
            
            %  Aceleracion del efector final [m/s2]            
            for i = 1 : CantPosiciones - 1
                Aux1_m_s2 = (VelX_EndEffector_m_s(i+1) - VelX_EndEffector_m_s(i)) / (TiempoActual_s(i+1) - TiempoActual_s(i));
                acelx_m_s2(i) = Aux1_m_s2;
                AcelX_EndEffector_m_s2 = [0 acelx_m_s2];
        
                Aux2_m_s2 = (VelY_EndEffector_m_s(i+1) - VelY_EndEffector_m_s(i)) / (TiempoActual_s(i+1) - TiempoActual_s(i));
                acely_m_s2(i) = Aux2_m_s2;
                AcelY_EndEffector_m_s2 = [0 acely_m_s2];
        
                Aux3_m_s2 = (VelZ_EndEffector_m_s(i+1) - VelZ_EndEffector_m_s(i)) / (TiempoActual_s(i+1) - TiempoActual_s(i));
                acelz_m_s2(i) = Aux3_m_s2;
                AcelZ_EndEffector_m_s2 = [0 acelz_m_s2];
            end
            
            AcelX_EndEffector_m_s2
            AcelY_EndEffector_m_s2
            AcelZ_EndEffector_m_s2            
          
            % Calculating undetermined multipliers (Ecuaciones de Lagrange)   
        
            % [ lan1,lan2,lan3 ] = undeterminedmultipliersfunc (g,CX,CY,AcelCx,AcelCy,AcelCz,RA,RB,Length2,Motorang1,0,Motorang2,120,Motorang3,240);
               
            for i = 1 : CantPosiciones
        
                a1 = 2.*Cx_m(i) - 2.*cos(Psi1_rad).*(rA_m + L2_m.*cos(q1_rad(i))) + 2.*rB_m.*cos(Psi1_rad);
                b1 = 2.*Cx_m(i) - 2.*cos(Psi2_rad).*(rA_m + L2_m.*cos(q2_rad(i))) + 2.*rB_m.*cos(Psi2_rad);
                c1 = 2.*Cx_m(i) - 2.*cos(Psi3_rad).*(rA_m + L2_m.*cos(q3_rad(i))) + 2.*rB_m.*cos(Psi3_rad);
                sol1 = AcelX_EndEffector_m_s2(i).*(3.*app.ml1 + app.mp);
        
                a2 = 2.*Cy_m(i) - 2.*sin(Psi1_rad).*(rA_m + L2_m.*cos(q1_rad(i))) + 2*rB_m*sin(Psi1_rad);
                b2 = 2.*Cy_m(i) - 2.*sin(Psi2_rad).*(rA_m + L2_m.*cos(q2_rad(i))) + 2*rB_m*sin(Psi2_rad);
                c2 = 2.*Cy_m(i) - 2.*sin(Psi3_rad).*(rA_m + L2_m.*cos(q3_rad(i))) + 2*rB_m*sin(Psi3_rad);
                sol2 = AcelY_EndEffector_m_s2(i).*(3.*app.ml1 + app.mp);
        
                a3 = 2.*Cz_m(i) - 2.*L2_m.*sin(q1_rad(i));
                b3 = 2.*Cz_m(i) - 2.*L2_m.*sin(q2_rad(i));
                c3 = 2.*Cz_m(i) - 2.*L2_m.*sin(q3_rad(i));               
                sol3 = (AcelZ_EndEffector_m_s2(i).*(3.*app.ml1 + app.mp)) - ((g_ms2.*(3.*app.ml1 + app.mp)));
                        
                A = [a1 b1 c1; a2 b2 c2; a3 b3 c3];
                B = [sol1; sol2; sol3];
                InvA = inv(A);
                Solution = InvA*B;
        
                LAN1 = Solution(1, 1);
                LAN2 = Solution(2, 1);
                LAN3 = Solution(3, 1);
        
                lan1(i) = LAN1;
                lan2(i) = LAN2;
                lan3(i) = LAN3;
        
            end 
        
            Lan1 = lan1;
            Lan2 = lan2;
            Lan3 = lan3;
                    
            % CALCULO DEL PAR                
            % [ Tm1,Tm2,Tm3 ] = Torquefunc(g,Acelq1,Acelq2,Acelq3,RA,RB,Length2,ml1,ml2,Motorang1,0,Motorang2,120,Motorang3,240);        
            TorqM1_Nm = (AcelAng_M1_rad_s2.*L2_m.^2.*(3.*app.ml1 + 4.*app.ml2))./3 - 2.*Lan1.*L2_m.*(rB_m.*sin(q1_rad) - rA_m.*sin(q1_rad) - Cz_m.*cos(q1_rad) + Cx_m.*cos(Psi1_rad).*sin(q1_rad) + Cy_m.*sin(Psi1_rad).*sin(q1_rad)) - (g_ms2.*L2_m.*cos(q1_rad).*(2*app.ml1 + app.ml2))./2;
            TorqM2_Nm = (AcelAng_M2_rad_s2.*L2_m.^2.*(3.*app.ml1 + 4.*app.ml2))./3 - 2.*Lan2.*L2_m.*(rB_m.*sin(q2_rad) - rA_m.*sin(q2_rad) - Cz_m.*cos(q2_rad) + Cx_m.*cos(Psi2_rad).*sin(q2_rad) + Cy_m.*sin(Psi2_rad).*sin(q2_rad)) - (g_ms2.*L2_m.*cos(q2_rad).*(2*app.ml1 + app.ml2))./2;
            TorqM3_Nm = (AcelAng_M3_rad_s2.*L2_m.^2.*(3.*app.ml1 + 4.*app.ml2))./3 - 2.*Lan3.*L2_m.*(rB_m.*sin(q3_rad) - rA_m.*sin(q3_rad) - Cz_m.*cos(q3_rad) + Cx_m.*cos(Psi3_rad).*sin(q3_rad) + Cy_m.*sin(Psi3_rad).*sin(q3_rad)) - (g_ms2.*L2_m.*cos(q3_rad).*(2*app.ml1 + app.ml2))./2;
                      
            ShowMessages = 0;   % Don't show singularity messages
        
            % Set torque plot
            cla(app.UITorque, 'reset');
            grid(app.UITorque, 'on');
            hold(app.UITorque, 'on'); 
            title(app.UITorque, 'TORQUE');
            xlabel(app.UITorque, 'Time (s)');
            ylabel(app.UITorque, 'Torque (Nm)');
                                 
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            % TorqM1_Nm
            % TorqM2_Nm
            % TorqM3_Nm
                   
            % Recorrer todas las posiciones del movimiento deseado
            
            for i = 1 : CantPosiciones
                % Update end effector position
                app.XPosition.Value = Cx_mm(i);
                app.YPosition.Value = Cy_mm(i);
                app.ZPosition.Value = Cz_mm(i);
                
                % Get XYZ desired positions from GUI
                EF_PosXYZ = [app.XPosition.Value, app.YPosition.Value, app.ZPosition.Value];
                                       
                % CALCULAR CINEMATICA INVERSA
                [Result_InvKinematics, Angulos_Caderas_deg, Alphas_deg, Thetas_deg, Tobillos_PosXYZ_REF0] = Inverse_Kinematics(ShowMessages, EF_PosXYZ, app.rA, app.rB, app.L1, app.L2);
                            
                % CALCULAR CINEMATICA DIRECTA                
                [Result_FwdKinematics, Caderas_PosXYZ_REF0, Rodillas_PosXYZ_REF0, EF_PosXYZ_Calculada_REF0, Esferas_PosXYZ_REF0] = Forward_Kinematics(ShowMessages, Angulos_Caderas_deg, app.rA, app.rB, app.L1, app.L2);
                
                % CALCULAR MATRIZ JACOBIANA
                [Result_JacobianMatrix, Jacobiano] = Jacobian_Matrix(ShowMessages, Angulos_Caderas_deg, Alphas_deg, Thetas_deg, app.L2);  
                
                if (Result_InvKinematics == 1 && Result_FwdKinematics == 1 && Result_JacobianMatrix == 1)
                    % Update hips angles on GUI
                    app.Q1Angle.Value = Angulos_Caderas_deg(1);
                    app.Q2Angle.Value = Angulos_Caderas_deg(2);
                    app.Q3Angle.Value = Angulos_Caderas_deg(3);
                    
                    % Plot robot
                    Plot_UpdateRobot(app, Caderas_PosXYZ_REF0, Rodillas_PosXYZ_REF0, Tobillos_PosXYZ_REF0, EF_PosXYZ, Esferas_PosXYZ_REF0, app.Leg1, app.Leg2, app.Leg3, app.Mobile_Base, app.Rodilla1_Text, app.Rodilla2_Text, app.Rodilla3_Text, app.Tobillo1_Text, app.Tobillo2_Text, app.Tobillo3_Text);
                end                
                
                pause(0.1);
                
                % Plot torques             
                Ti(i) = TiempoActual_s(i);
                TM1(i) = TorqM1_Nm(i);
                TM2(i) = TorqM2_Nm(i);
                TM3(i) = TorqM3_Nm(i);
                
                %TorqM1_Nm(i) = TorqM1_Nmm(i)  / 1000 % Convert from Nmm to Nm             
                plot(app.UITorque, Ti, TM1,  'Color', 'r', 'LineWidth', 2);
                Maxa_Nm = max(TorqM1_Nm);
                Mina_Nm = min(TorqM1_Nm);              
        
                %TorqM2_Nm(i) = TorqM2_Nmm(i) / 1000
                plot(app.UITorque, Ti, TM2,  'Color', 'g', 'LineWidth', 4);
                Maxb_Nm = max(TorqM2_Nm);
                Minb_Nm = min(TorqM2_Nm);             
        
                %TorqM3_Nm(i) = TorqM3_Nmm(i) / 1000
                plot(app.UITorque, Ti, TM3,  'Color', 'b', 'LineWidth', 2);
                Maxc_Nm = max(TorqM3_Nm);
                Minc_Nm = min(TorqM3_Nm);
                        
                MaxTorque_Nm = max([Maxa_Nm Maxb_Nm Maxc_Nm]);
                MinTorque_Nm = min([Mina_Nm Minb_Nm Minc_Nm]);
        
                % Calculate max values so far
                MaxTorquePower_Nm = max([abs(MaxTorque_Nm) abs(MinTorque_Nm)]);
                MaxAngVelocity_rad_s = max([abs(VelAngM1_rad_s) abs(VelAngM2_rad_s)  abs(VelAngM3_rad_s)]);
                Power1_W = abs(MaxTorque_Nm * MaxAngVelocity_rad_s);
                Power2_W = abs(MinTorque_Nm * MaxAngVelocity_rad_s);
                
                MaxPower_W = max([Power1_W Power2_W]);
                
                MaxVelX_EndEffector_m_s = max(abs(VelX_EndEffector_m_s));
                MaxVelY_EndEffector_m_s = max(abs(VelY_EndEffector_m_s));
                MaxVelZ_EndEffector_m_s = max(abs(VelZ_EndEffector_m_s));
                
                app.MaxVelX_m_s.Value = MaxVelX_EndEffector_m_s;
                app.MaxVelY_m_s.Value = MaxVelY_EndEffector_m_s;
                app.MaxVelZ_m_s.Value = MaxVelZ_EndEffector_m_s;
                
                MaxAcelX_EndEffector_m_s_2 = max(abs(AcelX_EndEffector_m_s2));
                MaxAcelY_EndEffector_m_s_2 = max(abs(AcelY_EndEffector_m_s2));
                MaxAcelZ_EndEffector_m_s_2 = max(abs(AcelZ_EndEffector_m_s2));
                
                app.MaxAcelX_m_s_2.Value = MaxAcelX_EndEffector_m_s_2;
                app.MaxAcelY_m_s_2.Value = MaxAcelY_EndEffector_m_s_2;
                app.MaxAcelZ_m_s_2.Value = MaxAcelZ_EndEffector_m_s_2;
                
                % Convertir unidades
                MaxTorquePower_Nm;
                MaxAngVelocity_RPM = (MaxAngVelocity_rad_s * 30) / pi(); % Vel angular en RPM
                MaxPower_W;
                
                % Show max values
                app.MaxTorque_Nm.Value = MaxTorquePower_Nm;   % Par en Nm                
                app.MaxSpeed_RPM.Value = MaxAngVelocity_RPM; % Max velocidad en RPM
                app.MaxPower_W.Value = MaxPower_W;  % Potencia en W
                          
                % Update torque plot dimensions                
                axis(app.UITorque, [0    (TiempoTotal_s)   MinTorque_Nm   MaxTorque_Nm]);               
            end
        
            %{
            MaxTorquepower = max([abs(MaxTorque) abs(MinTorque)])
            MaxAngVelocity = max([abs(WangM1) abs(WangM2)  abs(WangM3)])
            Power = (MaxTorquepower/1000)*MaxAngVelocity
            
            % Show max values
            app.MaxTorque_Nm.Value = MaxTorquepower/1000   % Par en Nm
            app.MaxSpeed_RPM.Value = MaxAngVelocity % Vel angular en rad/s
            app.MaxPower_W.Value = Power  % Potencia en W
            %}
            
            %set(handles.text9,'String',Power) 
            %set(handles.text13,'String',MaxTorquepower/1000)    %Par en Nm
            %set(handles.text11,'String',MaxAngVelocity) %Vel angular en rad/s
            
            %{
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %% Export Data
        
            M1Tor=TorqM1';
            dlmwrite('M1Torque.txt', M1Tor, 'delimiter', '\t','precision', 6)
            M2Tor=TorqM2';
            dlmwrite('M2Torque.txt', M2Tor, 'delimiter', '\t','precision', 6)
            M3Tor=TorqM3';
            dlmwrite('M3Torque.txt', M3Tor, 'delimiter', '\t','precision', 6)
        
            traj=[t',TorqM1',TorqM2',TorqM3'];
            [filename, pathname]=uiputfile({'*.txt','Delta''s files (*.txt)'},'Create a file');
            dlmwrite(strcat(pathname,filename), traj, 'delimiter', '\t', 'precision', 3,'newline', 'pc');
            %}
            
            %{
            % CONSTRAINS EQUATIONS
            % G1:
            Px1 = Cx + app.rB*cosd(0)
            Py1 = Cy + app.rB*sind(0)
            Pz1 = Cz
            
            Bx1 = (app.rA + app.L2*cosd(q1))*cosd(0)
            By1 = (app.rA + app.L2*cosd(q1))*sind(0)
            Bz1 = app.L2*sind(q1)
            
            g1 = (Px1 - Bx1)^2 + (Py1 - By1)^2 + (Pz1 - Bz1)^2 - app.L1^2
            %g1 = ((Cx+rB*cos(Psi1))-((rA+l2*cos(q1))*cos(Psi1)))^2 + ((Cy+rB*sin(Psi1))-((rA+l2*cos(q1))*sin(Psi1)))^2 + (Cz-l2*sin(q1))^2   %Falta - l1^2
            
            % G2:
            Px2 = Cx + app.rB*cosd(120)
            Py2 = Cy + app.rB*sind(120)
            Pz2 = Cz
            
            Bx2 = (app.rA + app.L2*cosd(q2))*cosd(120)
            By2 = (app.rA + app.L2*cosd(q2))*sind(120)
            Bz2 = app.L2*sind(q2)
            
            g2 = (Px2 - Bx2)^2 + (Py2 - By2)^2 + (Pz2 - Bz2)^2 - app.L1^2
            %g2 = ((Cx+rB*cos(Psi2))-((rA+l2*cos(q2))*cos(Psi2)))^2 + ((Cy+rB*sin(Psi2))-((rA+l2*cos(q2))*sin(Psi2)))^2 + (Cz-l2*sin(q2))^2    %Falta - l1^2
            
            % G3:                        
            Px3 = Cx + app.rB*cosd(240)
            Py3 = Cy + app.rB*sind(240)
            Pz3 = Cz
            
            Bx3 = (app.rA + app.L2*cosd(q3))*cosd(240)
            By3 = (app.rA + app.L2*cosd(q3))*sind(240)
            Bz3 = app.L2*sind(q3)
            
            g3 = (Px3 - Bx3)^2 + (Py3 - By3)^2 + (Pz3 - Bz3)^2 - app.L1^2           
            %g3 = ((Cx+rB*cos(Psi3))-((rA+l2*cos(q3))*cos(Psi3)))^2 + ((Cy+rB*sin(Psi3))-((rA+l2*cos(q3))*sin(Psi3)))^2 + (Cz-l2*sin(q3))^2    %Falta - l1^2
            
            % KINETIC ENERGY
            Tp = (1/2)*mp*(AcelCx^2 + AcelCy^2 + AcelCz^2)   %La ecuacion usa aceleracion o velocidad?
            
            T1l2 = (1/2)*(ml2*l2^2 + (1/3)*ml2*l2^2)*Acelq1^2
            T2l2 = (1/2)*(ml2*l2^2 + (1/3)*ml2*l2^2)*Acelq2^2
            T3l2 = (1/2)*(ml2*l2^2 + (1/3)*ml2*l2^2)*Acelq3^2
            
            T1l1 = (1/2)*ml1*(AcelCx^2 + AcelCy^2+AcelCz^2) + (1/2)*ml1*l2^2*Acelq1^2
            T2l1 = (1/2)*ml1*(AcelCx^2 + AcelCy^2+AcelCz^2) + (1/2)*ml1*l2^2*Acelq2^2
            T3l1 = (1/2)*ml1*(AcelCx^2 + AcelCy^2+AcelCz^2) + (1/2)*ml1*l2^2*Acelq3^2
            
            Kinetic = simplify(Tp + T1l2 + T2l2 + T3l2 + T1l1 + T2l1 + T3l1)
            
            % POTENTIAL ENERGY
            Vp = mp*g*(H - Cz)
            
            V1l2 = ml2*g*H - (1/2)*ml2*g*(l2*sin(q1))
            V2l2 = ml2*g*H - (1/2)*ml2*g*(l2*sin(q2))
            V3l2 = ml2*g*H - (1/2)*ml2*g*(l2*sin(q3))
            
            V1l1 = 2*ml1*g*H - ml1*g*(Cz + l2*sin(q1))
            V2l1 = 2*ml1*g*H - ml1*g*(Cz + l2*sin(q2))
            V3l1 = 2*ml1*g*H - ml1*g*(Cz + l2*sin(q3))
            
            Potential = simplify(Vp + V1l2 + V2l2 + V3l2 + V1l1 + V2l1 + V3l1)
            %}
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DeltaRobotGUI = uifigure('Visible', 'off');
            app.DeltaRobotGUI.Name = 'Delta Robot GUI';
            app.DeltaRobotGUI.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.DeltaRobotGUI);
            app.LeftPanel = uipanel(app.GridLayout);
            app.DYNAMICSPanel = uipanel(app.LeftPanel);
            app.DYNAMICSPanel.Title = 'DYNAMICS';
            app.CalculateDynamicsButton = uibutton(app.DYNAMICSPanel, 'push');
            app.CalculateDynamicsButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateDynamicsButtonPushed, true);
            app.CalculateDynamicsButton.Text = 'Calculate';
            app.MaxtorqueNmLabel = uilabel(app.DYNAMICSPanel);
            app.MaxtorqueNmLabel.Text = 'Max torque [Nm]';
            app.MaxTorque_Nm = uieditfield(app.DYNAMICSPanel, 'numeric');
            app.MaxTorque_Nm.Editable = 'off';
            app.MaxspeedRPMLabel = uilabel(app.DYNAMICSPanel);
            app.MaxspeedRPMLabel.Text = 'Max speed [RPM]';
            app.MaxSpeed_RPM = uieditfield(app.DYNAMICSPanel, 'numeric');
            app.MaxSpeed_RPM.Editable = 'off';
            app.MaxpowerWLabel = uilabel(app.DYNAMICSPanel);
            app.MaxpowerWLabel.Text = 'Max power [W]';
            app.MaxPower_W = uieditfield(app.DYNAMICSPanel, 'numeric');
            app.MaxPower_W.Editable = 'off';
            app.MovementtimesLabel = uilabel(app.DYNAMICSPanel);
            app.MovementtimesLabel.Text = 'Movement time [s]';
            app.MovementTime_s = uieditfield(app.DYNAMICSPanel, 'numeric');
            app.MovementTime_s.Value = 10;
            app.MaxvelXmsLabel = uilabel(app.DYNAMICSPanel);
            app.MaxvelXmsLabel.Text = 'Max vel X [m/s]';
            app.MaxVelX_m_s = uieditfield(app.DYNAMICSPanel, 'numeric');
            app.MaxVelX_m_s.Editable = 'off';
            app.MaxvelYmsLabel = uilabel(app.DYNAMICSPanel);
            app.MaxvelYmsLabel.Text = 'Max vel Y [m/s]';
            app.MaxVelY_m_s = uieditfield(app.DYNAMICSPanel, 'numeric');
            app.MaxVelY_m_s.Editable = 'off';
            app.MaxvelZmsLabel = uilabel(app.DYNAMICSPanel);
            app.MaxvelZmsLabel.Text = 'Max vel Z [m/s]';
            app.MaxVelZ_m_s = uieditfield(app.DYNAMICSPanel, 'numeric');
            app.MaxVelZ_m_s.Editable = 'off';
            app.MaxacelXms2Label = uilabel(app.DYNAMICSPanel);
            app.MaxacelXms2Label.Text = 'Max acel X [m/s^2]';
            app.MaxAcelX_m_s_2 = uieditfield(app.DYNAMICSPanel, 'numeric');
            app.MaxAcelX_m_s_2.Editable = 'off';
            app.MaxacelYms2Label = uilabel(app.DYNAMICSPanel);
            app.MaxacelYms2Label.Text = 'Max acel Y [m/s^2]';
            app.MaxAcelY_m_s_2 = uieditfield(app.DYNAMICSPanel, 'numeric');
            app.MaxAcelY_m_s_2.Editable = 'off';
            app.MaxacelZms2Label = uilabel(app.DYNAMICSPanel);
            app.MaxacelZms2Label.Text = 'Max acel Z [m/s^2]';
            app.MaxAcelZ_m_s_2 = uieditfield(app.DYNAMICSPanel, 'numeric');
            app.MaxAcelZ_m_s_2.Editable = 'off';
            app.COMMUNICATIONSPanel = uipanel(app.LeftPanel);
            app.COMMUNICATIONSPanel.Title = 'COMMUNICATIONS';
            app.TCPDisconnectButton = uibutton(app.COMMUNICATIONSPanel, 'push');
            app.TCPDisconnectButton.ButtonPushedFcn = createCallbackFcn(app, @TCPDisconnectButtonPushed, true);
            app.TCPDisconnectButton.Text = 'TCP - Disconnect';
            app.TCPConnectButton = uibutton(app.COMMUNICATIONSPanel, 'push');
            app.TCPConnectButton.ButtonPushedFcn = createCallbackFcn(app, @TCPConnectButtonPushed, true);
            app.TCPConnectButton.Text = 'TCP - Connect';
            app.ENDEFFECTORPOSITIONPanel = uipanel(app.LeftPanel);
            app.ENDEFFECTORPOSITIONPanel.Title = 'END EFFECTOR POSITION';
            app.XmmEditFieldLabel = uilabel(app.ENDEFFECTORPOSITIONPanel);
            app.XmmEditFieldLabel.Text = 'X [mm]';
            app.XPosition = uieditfield(app.ENDEFFECTORPOSITIONPanel, 'numeric');
            app.YmmLabel = uilabel(app.ENDEFFECTORPOSITIONPanel);
            app.YmmLabel.Text = 'Y [mm]';
            app.YPosition = uieditfield(app.ENDEFFECTORPOSITIONPanel, 'numeric');
            app.ZmmLabel = uilabel(app.ENDEFFECTORPOSITIONPanel);
            app.ZmmLabel.Text = 'Z [mm]';
            app.ZPosition = uieditfield(app.ENDEFFECTORPOSITIONPanel, 'numeric');
            app.ZPosition.Value = -200;
            app.InverseKinematicsButton = uibutton(app.ENDEFFECTORPOSITIONPanel, 'push');
            app.InverseKinematicsButton.ButtonPushedFcn = createCallbackFcn(app, @InverseKinematicsButtonPushed, true);
            app.InverseKinematicsButton.Text = 'Move (Inverse kinematics)';
            app.WORKSPACEPanel = uipanel(app.LeftPanel);
            app.WORKSPACEPanel.Title = 'WORKSPACE';
            app.WorkspaceInvKinematicsButton = uibutton(app.WORKSPACEPanel, 'push');
            app.WorkspaceInvKinematicsButton.ButtonPushedFcn = createCallbackFcn(app, @WorkspaceInvKinematicsButtonPushed, true);
            app.WorkspaceInvKinematicsButton.Text = 'Workspace (Inverse kinematics)';
            app.WorkspaceFwdKinematicsButton = uibutton(app.WORKSPACEPanel, 'push');
            app.WorkspaceFwdKinematicsButton.ButtonPushedFcn = createCallbackFcn(app, @WorkspaceFwdKinematicsButtonPushed, true);
            app.WorkspaceFwdKinematicsButton.Text = 'Workspace (Forward kinematics)';
            app.ACTIVEJOINTSPanel = uipanel(app.LeftPanel);
            app.ACTIVEJOINTSPanel.Title = 'ACTIVE JOINTS';
            app.Q3Label = uilabel(app.ACTIVEJOINTSPanel);
            app.Q3Label.Text = 'Q3 [°]';
            app.Q3Angle = uieditfield(app.ACTIVEJOINTSPanel, 'numeric');
            app.Q3Angle.ValueDisplayFormat = '%11.6g';
            app.Q1Label = uilabel(app.ACTIVEJOINTSPanel);
            app.Q1Label.Text = 'Q1 [°]';
            app.Q1Angle = uieditfield(app.ACTIVEJOINTSPanel, 'numeric');
            app.Q1Angle.ValueDisplayFormat = '%11.6g';
            app.ForwardKinematicsButton = uibutton(app.ACTIVEJOINTSPanel, 'push');
            app.ForwardKinematicsButton.ButtonPushedFcn = createCallbackFcn(app, @ForwardKinematicsButtonPushed, true);
            app.ForwardKinematicsButton.Text = 'Move (Forward kinematics)';
            app.Q2Label = uilabel(app.ACTIVEJOINTSPanel);
            app.Q2Label.Text = 'Q2 [°]';
            app.Q2Angle = uieditfield(app.ACTIVEJOINTSPanel, 'numeric');
            app.Q2Angle.ValueDisplayFormat = '%11.6g';
            app.PATHGENERATIONPanel = uipanel(app.LeftPanel);
            app.PATHGENERATIONPanel.Title = 'PATH GENERATION';
            app.LinearpathButton = uibutton(app.PATHGENERATIONPanel, 'push');
            app.LinearpathButton.ButtonPushedFcn = createCallbackFcn(app, @LinearpathButtonPushed, true);
            app.LinearpathButton.Text = 'Linear path';
            app.InitialmmLabel = uilabel(app.PATHGENERATIONPanel);
            app.InitialmmLabel.Text = 'Initial [mm]';
            app.EndmmLabel = uilabel(app.PATHGENERATIONPanel);
            app.EndmmLabel.Text = 'End [mm]';
            app.LinearPath_X_End = uieditfield(app.PATHGENERATIONPanel, 'numeric');
            app.LinearPath_X_End.Value = 150;
            app.LinearPath_Y_Initial = uieditfield(app.PATHGENERATIONPanel, 'numeric');
            app.LinearPath_Y_Initial.Value = -150;
            app.LinearPath_Y_End = uieditfield(app.PATHGENERATIONPanel, 'numeric');
            app.LinearPath_Y_End.Value = 150;
            app.LinearPath_Z_Initial = uieditfield(app.PATHGENERATIONPanel, 'numeric');
            app.LinearPath_Z_Initial.Value = -300;
            app.LinearPath_Z_End = uieditfield(app.PATHGENERATIONPanel, 'numeric');
            app.LinearPath_Z_End.Value = -300;
            app.LinearPath_X_Initial = uieditfield(app.PATHGENERATIONPanel, 'numeric');
            app.LinearPath_X_Initial.Value = -150;
            app.XYZLabel = uilabel(app.PATHGENERATIONPanel);
            app.XYZLabel.Text = {'X'; ''; 'Y'; ''; 'Z'};
            app.DisplacementintervalmmLabel = uilabel(app.PATHGENERATIONPanel);
            app.DisplacementintervalmmLabel.Text = 'Displacement interval [mm]';
            app.LinearPath_Interval_mm = uieditfield(app.PATHGENERATIONPanel, 'numeric');
            app.LinearPath_Interval_mm.Value = 10;
            app.LinearvelocitymsLabel = uilabel(app.PATHGENERATIONPanel);
            app.LinearvelocitymsLabel.Text = 'Linear velocity [m/s]';
            app.LinearPath_Vel_m_s = uieditfield(app.PATHGENERATIONPanel, 'numeric');
            app.LinearPath_Vel_m_s.Value = 1;
            app.Linearaccelerationms2Label = uilabel(app.PATHGENERATIONPanel);
            app.Linearaccelerationms2Label.Text = 'Linear acceleration [m/s^2]';
            app.LinearPath_Acc_m_s2 = uieditfield(app.PATHGENERATIONPanel, 'numeric');
            app.LinearPath_Acc_m_s2.Value = 10;
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            xlabel(app.UIAxes, 'X [mm]')
            ylabel(app.UIAxes, 'Y [mm]')
            zlabel(app.UIAxes, 'Z [mm]')
            app.UITorque = uiaxes(app.RightPanel);
            title(app.UITorque, 'TORQUE')
            xlabel(app.UITorque, 'Time [s]')
            ylabel(app.UITorque, 'Torque [Nm]')
            app.ShowspheresCheckBox = uicheckbox(app.RightPanel);
            app.ShowspheresCheckBox.Text = 'Show spheres';
            % Show the figure after all components are created
            app.DeltaRobotGUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DeltaRobot_GUI_v2_CFPZ

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DeltaRobotGUI)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DeltaRobotGUI)
        end
    end
end